classdef TTP_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ComponentManagerTab             matlab.ui.container.Tab
        dataType                        matlab.ui.container.TabGroup
        RequiredTab                     matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ToDataScreeningButton           matlab.ui.control.Button
        CASisusedAsauniqueidentifiertolinkdatafromLabel_2  matlab.ui.control.Label
        ImportDataButton                matlab.ui.control.Button
        ClearTableButton                matlab.ui.control.Button
        SaveTableButton                 matlab.ui.control.Button
        AddDataButton                   matlab.ui.control.Button
        ManualDataEntryWindowTextArea   matlab.ui.control.TextArea
        ManualDataEntryWindowTextAreaLabel  matlab.ui.control.Label
        VariableSelectorButtonGroup     matlab.ui.container.ButtonGroup
        CASButton                       matlab.ui.control.ToggleButton
        ComponentButton                 matlab.ui.control.ToggleButton
        cp_J_per_K_molButton            matlab.ui.control.ToggleButton
        cp_temperature_KButton          matlab.ui.control.ToggleButton
        enthalpy_vaporization_kJ_per_molButton  matlab.ui.control.ToggleButton
        enthalpy_vaporization_temperature_KButton  matlab.ui.control.ToggleButton
        saturation_pressure_kPaButton   matlab.ui.control.ToggleButton
        saturation_temperature_KButton  matlab.ui.control.ToggleButton
        criticalPressure_kPaButton      matlab.ui.control.ToggleButton
        criticalTemperature_KButton     matlab.ui.control.ToggleButton
        AllinthissectionisusedforLabel  matlab.ui.control.Label
        CASisusedAsauniqueidentifiertolinkdatafromLabel  matlab.ui.control.Label
        dataDisplayTable                matlab.ui.control.Table
        DataScreenerTab                 matlab.ui.container.Tab
        ToOptimizerButton               matlab.ui.control.Button
        ScreenedDataLabel               matlab.ui.control.Label
        SourceDataLabel                 matlab.ui.control.Label
        table_sourceData                matlab.ui.control.Table
        table_screeningData             matlab.ui.control.Table
        ScreeningProcessTab             matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        StartScreeningButton            matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        ScreeningTypeTab                matlab.ui.container.Tab
        AutoViaRegressionTreesButton    matlab.ui.control.Button
        ManualButton                    matlab.ui.control.Button
        ManualTab                       matlab.ui.container.Tab
        UsetheplotbrushtooltomarkupdataLabel  matlab.ui.control.Label
        CompleteManualScreeningButton   matlab.ui.control.Button
        RemoveMarkedOutliersButton      matlab.ui.control.Button
        AutoTab                         matlab.ui.container.Tab
        InputParametersPanel            matlab.ui.container.Panel
        NumberofTreesEditField          matlab.ui.control.NumericEditField
        NumberofTreesEditFieldLabel     matlab.ui.control.Label
        MultiplierfortheIQRFenceEditField  matlab.ui.control.NumericEditField
        MultiplierfortheIQRFenceLabel   matlab.ui.control.Label
        BinningSizeEditField            matlab.ui.control.NumericEditField
        BinningSizeLabel                matlab.ui.control.Label
        CompleteScreeningButton         matlab.ui.control.Button
        RejectOutliersButton            matlab.ui.control.Button
        AcceptOutliersButton            matlab.ui.control.Button
        StartAutoScreeningButton        matlab.ui.control.Button
        ToComponentManagerButton        matlab.ui.control.Button
        PlotAxisPanel                   matlab.ui.container.Panel
        DataTypeDropDown_screener       matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        ComponentDropDown_screener      matlab.ui.control.DropDown
        ComponentLabel                  matlab.ui.control.Label
        PlotDataButton                  matlab.ui.control.Button
        YAxisDropDown                   matlab.ui.control.DropDown
        YAxisLabel                      matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisLabel                      matlab.ui.control.Label
        UIAxes_ScreeningData            matlab.ui.control.UIAxes
        OptimizerTab                    matlab.ui.container.Tab
        ToDataPlottingButton            matlab.ui.control.Button
        DisplayOptionButtonGroup        matlab.ui.container.ButtonGroup
        LogButton                       matlab.ui.control.ToggleButton
        OutputTableButton               matlab.ui.control.ToggleButton
        Label_infoText2OptimzationTab   matlab.ui.control.Label
        OptimizingLabel                 matlab.ui.control.Label
        OptimizingImage_spin            matlab.ui.control.Image
        Label_infoTextOptimzationTab    matlab.ui.control.Label
        TabGroup_OptimizationOutputs    matlab.ui.container.TabGroup
        TwuOutputTab                    matlab.ui.container.Tab
        Twu_filePath                    matlab.ui.control.Label
        Twu_RunIdentifierLabel          matlab.ui.control.Label
        Twu_RunIDLabel                  matlab.ui.control.Label
        Twu_SaveOptimizationResultsButton  matlab.ui.control.Button
        Twu_OptimizationSetNameEditField  matlab.ui.control.EditField
        OptimizationSetNameEditFieldLabel  matlab.ui.control.Label
        OptimizingImage_bar_3           matlab.ui.control.Image
        UITable_TwuOutput               matlab.ui.control.Table
        SoaveOutputTab                  matlab.ui.container.Tab
        Soave_filePath                  matlab.ui.control.Label
        Soave_RunIdentifierLabel        matlab.ui.control.Label
        Soave_RunIDLabel                matlab.ui.control.Label
        Soave_OptimizationSetNameEditField  matlab.ui.control.EditField
        OptimizationSetNameEditFieldLabel_2  matlab.ui.control.Label
        Soave_SaveOptimizationResultsButton  matlab.ui.control.Button
        OptimizingImage_bar_2           matlab.ui.control.Image
        UITable_SoaveOutput             matlab.ui.control.Table
        LogTab                          matlab.ui.container.Tab
        OptimizingImage_bar_1           matlab.ui.control.Image
        OptimizationProgressTextArea    matlab.ui.control.TextArea
        OptimizationLogTextAreaLabel    matlab.ui.control.Label
        StartOptimizationButton         matlab.ui.control.Button
        ToDataScreeningButton_2         matlab.ui.control.Button
        ExperimentalDataSelectionPanel  matlab.ui.container.Panel
        ComponentDropDown_optimizer     matlab.ui.control.DropDown
        ComponentDropDown_2Label        matlab.ui.control.Label
        DataTypeDropDown_optimizer      matlab.ui.control.DropDown
        DataTypeLabel                   matlab.ui.control.Label
        Panel2                          matlab.ui.container.Panel
        OptimizerSettingsPanel          matlab.ui.container.Panel
        CpTrUpperLimitEditField         matlab.ui.control.NumericEditField
        CpTrUpperLimitLabel             matlab.ui.control.Label
        PropertiestooptimizePanel       matlab.ui.container.Panel
        SaturatedLiquidHeatCapacityCheckBox  matlab.ui.control.CheckBox
        VaporizationEnthalpyCheckBox    matlab.ui.control.CheckBox
        SaturationPressureCheckBox      matlab.ui.control.CheckBox
        ApplyLeGuennecConstraintsCheckBox  matlab.ui.control.CheckBox
        ErrorFunctionDropDown           matlab.ui.control.DropDown
        ErrorTypeLabel                  matlab.ui.control.Label
        TabGroup_alphaFunction          matlab.ui.container.TabGroup
        TwuTab                          matlab.ui.container.Tab
        InitialEstimationforTwuAlphaParametersPanel  matlab.ui.container.Panel
        Label_3                         matlab.ui.control.Label
        TTr_1exp_21Tr_3Label            matlab.ui.control.Label
        Alpha1EditField                 matlab.ui.control.NumericEditField
        Alpha1EditFieldLabel            matlab.ui.control.Label
        Alpha3EditField                 matlab.ui.control.NumericEditField
        Alpha3EditFieldLabel            matlab.ui.control.Label
        Alpha2EditField                 matlab.ui.control.NumericEditField
        Alpha2EditFieldLabel            matlab.ui.control.Label
        SoaveTab                        matlab.ui.container.Tab
        InitialEstimationforSoavemParameterPanel  matlab.ui.container.Panel
        Label_4                         matlab.ui.control.Label
        mEditField                      matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        ModelOptionsPanel               matlab.ui.container.Panel
        AlphaFunctionDropDown           matlab.ui.control.DropDown
        AlphaFunctionLabel              matlab.ui.control.Label
        CEoSDropDown                    matlab.ui.control.DropDown
        CEoSLabel                       matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ClearWeighingFactorsSetButton   matlab.ui.control.Button
        SaveWeightingFactorSetsButton   matlab.ui.control.Button
        ManualEntryWindow_weightingFactor  matlab.ui.control.TextArea
        ManualEntryWindowTextAreaLabel  matlab.ui.control.Label
        ImportWeightsButton             matlab.ui.control.Button
        AddWeighingFactorsButton        matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        WeighingFactorVariablesButtonGroup  matlab.ui.container.ButtonGroup
        SetNameEditField                matlab.ui.control.EditField
        SetNameEditFieldLabel           matlab.ui.control.Label
        W_HButton                       matlab.ui.control.ToggleButton
        W_CpButton                      matlab.ui.control.ToggleButton
        W_PButton                       matlab.ui.control.ToggleButton
        UITable_weightingFactorSet      matlab.ui.control.Table
        WeighingFactorsSetLabel         matlab.ui.control.Label
        PlotterTab                      matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ToOptimizerButton_2             matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        TwuTab_2                        matlab.ui.container.Tab
        UITable_alphaTable_Twu          matlab.ui.control.Table
        TabGroup_Plotter_Twu            matlab.ui.container.TabGroup
        DataSetTab_Plotter_Twu          matlab.ui.container.Tab
        RunIDDropDown_plotter_Twu       matlab.ui.control.DropDown
        RunIDDropDownLabel              matlab.ui.control.Label
        DataTypeDropDown_plotter_Twu    matlab.ui.control.DropDown
        DataTypeDropDown_2Label_2       matlab.ui.control.Label
        ComponentDropDown_plotter_Twu   matlab.ui.control.DropDown
        ComponentLabel_3                matlab.ui.control.Label
        ManualTab_Plotter_Twu           matlab.ui.container.Tab
        UpdateParametersButton_Twu      matlab.ui.control.Button
        Alpha3EditField_plotter         matlab.ui.control.NumericEditField
        Alpha3EditField_3Label          matlab.ui.control.Label
        Alpha2EditField_plotter         matlab.ui.control.NumericEditField
        Alpha2EditField_3Label          matlab.ui.control.Label
        Alpha1EditField_plotter         matlab.ui.control.NumericEditField
        Alpha1EditField_3Label          matlab.ui.control.Label
        AlphaParmatersSourceButtonGroup_Twu  matlab.ui.container.ButtonGroup
        ManualEntryButton_Plotter_Twu   matlab.ui.control.ToggleButton
        FromDataSetButton_Plotter_Twu   matlab.ui.control.ToggleButton
        SoaveTab_Soave                  matlab.ui.container.Tab
        UITable_mParameterTable_Soave   matlab.ui.control.Table
        TabGroup_Plotter_Soave          matlab.ui.container.TabGroup
        DataSetTab_Plotter_Soave        matlab.ui.container.Tab
        RunIDDropDown_plotter_Soave     matlab.ui.control.DropDown
        RunDropDown_3Label              matlab.ui.control.Label
        DataTypeDropDown_plotter_Soave  matlab.ui.control.DropDown
        DataTypeDropDown_2Label_3       matlab.ui.control.Label
        ComponentDropDown_plotter_Soave  matlab.ui.control.DropDown
        ComponentLabel_4                matlab.ui.control.Label
        ManualTab_Plotter_Soave         matlab.ui.container.Tab
        UpdateParametersButton_Soave    matlab.ui.control.Button
        mParameterEditField             matlab.ui.control.NumericEditField
        mLabel_2                        matlab.ui.control.Label
        GeneralizedModelTab             matlab.ui.container.Tab
        GeneralizedModelDropDown        matlab.ui.control.DropDown
        GeneralizedModelLabel           matlab.ui.control.Label
        AlphaParametersSourceButtonGroup_Soave  matlab.ui.container.ButtonGroup
        GeneralizedModelButton_Plotter_Soave  matlab.ui.control.ToggleButton
        ManualEntryButton_Plotter_Soave  matlab.ui.control.ToggleButton
        FromDataSetButton_Plotter_Soave  matlab.ui.control.ToggleButton
        ModelSeletionPanel              matlab.ui.container.Panel
        PropertyDropDown                matlab.ui.control.DropDown
        PropertyDropDownLabel           matlab.ui.control.Label
        AlphaFunctionDropDown_2         matlab.ui.control.DropDown
        AlphaFunctionDropDown_2Label    matlab.ui.control.Label
        CEoSDropDown_2                  matlab.ui.control.DropDown
        CEoSLabel_2                     matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function outputFunction = createOutputFunction(app)
            % Output function to display optimization progress in a text area.
            outputFunction = @(x,optimValues,state) updateProgress(x, optimValues, state, app);
        end
        
        function stop = updateProgress(~, optimValues, state, app)
            stop = false;
            switch state
                case 'iter'
                    % Append current iteration information to the Text Area.
                    currentLog = app.x.Value;
                    newLog = sprintf('Iteration: %d, Fval: %.4f', optimValues.iteration, optimValues.fval);
                    app.x.Value = [currentLog; {newLog}];
                    drawnow; % To update the UI immediately
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define the correct backup file path
            backupFolderPath = fullfile(getenv('USERPROFILE'), 'Documents', 'TTP_GUI_Data', 'optimizationResults', 'backedUpFiles');
        
            % Get a list of all CSV files in the backup folder
            backupFiles = dir(fullfile(backupFolderPath, '*.csv'));
        
            % Loop through all backup files to check their age based on the RunID column
            for i = 1:length(backupFiles)
                % Construct the full file path
                fullFilePath = fullfile(backupFolderPath, backupFiles(i).name);
        
                % Read the CSV file into a table
                try
                    dataTable = readtable(fullFilePath);
                catch
                    warning('Unable to read file: %s. Skipping.', fullFilePath);
                    continue; % Skip the file if there was an issue reading it
                end
        
                % Check if the table has a 'RunID' column
                if ~ismember('RunID', dataTable.Properties.VariableNames)
                    warning('File %s does not contain a RunID column. Skipping.', fullFilePath);
                    continue; % Skip if the file does not have the expected column
                end
        
                % Extract the RunID column
                runIDColumn = dataTable.RunID;
        
                % Loop through each entry in the RunID column to check its age
                deleteFile = false; % Flag to mark if the file should be deleted
                for j = 1:length(runIDColumn)
                    runID = runIDColumn{j}; % Get the current RunID as a string
        
                    % Extract the timestamp from the RunID (finding the second-to-last underscore)
                    try
                        % Find all positions of underscores in the string
                        underscorePositions = strfind(runID, '_');
                        
                        % Check if there are enough underscores to extract the timestamp
                        if length(underscorePositions) < 2
                            warning('Invalid RunID format: %s. Skipping this entry.', runID);
                            continue;
                        end
        
                        % Extract the part after the second-to-last underscore
                        timestampStr = runID(underscorePositions(end-1) + 1 : end);
        
                        % Convert the timestamp to a datetime object
                        runDate = datetime(timestampStr, 'InputFormat', 'yyyyMMdd_HHmmss');
                    catch
                        warning('Invalid timestamp format in RunID: %s. Skipping this entry.', runID);
                        continue; % Skip this RunID if the format is incorrect
                    end
        
                    % Calculate the age of the RunID in days
                    runAgeDays = days(datetime('now') - runDate);
        
                    % If any RunID in the file is older than 7 days, mark the file for deletion
                    if runAgeDays > 7
                        deleteFile = true;
                        break;
                    end
                end
        
                % Delete the file if it contains any outdated RunIDs
                if deleteFile
                    delete(fullFilePath);
                    fprintf('Deleted file: %s (contains outdated RunID)\n', fullFilePath);
                end
            end
        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            % Get the column headers from UITable
             columnHeaders = app.dataDisplayTable.ColumnName;
        
            % Get the selected X and Y axis columns from the dropdowns
            selectedXColumn = app.XAxisDropDown.Value;
            selectedYColumn = app.YAxisDropDown.Value;
        
            % Find the index of the selected columns in the column headers
            xColumnIdx = find(strcmp(columnHeaders, selectedXColumn));
            yColumnIdx = find(strcmp(columnHeaders, selectedYColumn));
        
            % Define the folder path based on the DataTypeDropDown selection
            selectedDataType = app.DataTypeDropDown_screener.Value;
            
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown_screener.Value;
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the file exists
            if ~exist(fullFilePath, 'file')
                % Display warning if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');
                return;
            end
        
            % Read the data from the file (assuming a CSV format)
            opts = detectImportOptions(fullFilePath);
            tableData = readtable(fullFilePath, opts);
        
            % Get the data from the selected columns for X and Y
            xData = tableData{:, xColumnIdx};
            yData = tableData{:, yColumnIdx};
        
            % Check if the data extracted is numeric
            if ~isnumeric(xData) || ~isnumeric(yData)
                uialert(app.UIFigure, 'The selected columns must contain numeric data for plotting.', 'Plot Error');
                return;
            end
        
            % Check if the two data sets have the same number of rows
            if length(rmmissing(xData)) ~= length(rmmissing(yData))
                uialert(app.UIFigure, 'The selected X and Y data sets must have the same number of rows.', 'Data Size Mismatch');
                return;
            end
        
            % Modify the column names for the labels by replacing "_" with a space
            modifiedXLabel = strrep(selectedXColumn, '_', ' ');
            modifiedYLabel = strrep(selectedYColumn, '_', ' ');
        
            % Plot the data on the UIAxes
            plot(app.UIAxes_ScreeningData, xData, yData, Marker = "o", MarkerSize=8, LineStyle = "none",Color = "#44AA99",LineWidth=1);
        
            % Label the axes
            xlabel(app.UIAxes_ScreeningData, modifiedXLabel);
            ylabel(app.UIAxes_ScreeningData, modifiedYLabel);
            title(app.UIAxes_ScreeningData, sprintf('Plot of %s vs %s', modifiedYLabel, modifiedXLabel));
        
            % Update the screening data table with X and Y data
            % Create tables for X and Y data with appropriate variable names
            xColumnTable = table(xData, 'VariableNames', {modifiedXLabel});
            yColumnTable = table(yData, 'VariableNames', {modifiedYLabel});

            % Check for duplicate variable names before updating the table
            if strcmp(modifiedXLabel, modifiedYLabel)
                uialert(app.UIFigure, 'The X and Y axes have the same name. Please select different columns for X and Y axes.', 'Duplicate Column Names');
                return;
            end            
        
            % Update the screening data tables in app.table_screeningData and app.table_sourceData
            % Ensure that the X-axis data is in the first column and Y-axis data is in the second column
            updatedScreeningData = [xColumnTable, yColumnTable];
            app.table_screeningData.Data = updatedScreeningData;
            app.table_sourceData.Data = updatedScreeningData;

            % Update column headers of the screening tables
            app.table_screeningData.ColumnName = {modifiedXLabel, modifiedYLabel};
            app.table_sourceData.ColumnName = {modifiedXLabel, modifiedYLabel};
        end

        % Button pushed function: StartScreeningButton
        function StartScreeningButtonPushed(app, event)
            
            % Set the selected tab in the TabGroup to ScreeningTab
            app.ScreeningProcessTab.SelectedTab = app.ScreeningTypeTab;

        end

        % Button pushed function: ToDataScreeningButton
        function ToDataScreeningButtonPushed(app, event)
           % Set the selected tab in the TabGroup to ScreeningTab
            app.TabGroup.SelectedTab = app.DataScreenerTab;
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            % Open file selection dialog for CSV files
            [fileName, filePath] = uigetfile('*.csv', 'Select a CSV File to Import');
            
            % Check if the user did not cancel the file selection
            if isequal(fileName, 0)
                % User canceled the file selection
                return;
            end
        
            % Construct the full file path
            fullFilePath = fullfile(filePath, fileName);
        
            % Read the CSV file into a table
            try
                importedTable = readtable(fullFilePath);
            catch
                % Alert the user if there was an error reading the CSV
                uialert(app.UIFigure, 'Failed to read the CSV file. Please make sure it is formatted correctly.', 'Read Error');
                return;
            end
        
            % Convert the imported table to a cell array
            importedData = table2cell(importedTable);
        
            % Update the UITable's data
            app.dataDisplayTable.Data = importedData;
        
            % Inform the user that the CSV file has been imported successfully
            uialert(app.UIFigure, 'Data has been successfully imported from the CSV file.', 'Import Successful');
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            % Set the UITable's Data property to an empty cell array to clear the table
            app.dataDisplayTable.Data = {};
        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
             % Get the data entered by the user
            userInput = app.ManualDataEntryWindowTextArea.Value; % Get input as a string
        
            % Split the user input based on delimiters (commas, new lines, spaces, tabs)
            rows = split(userInput, '\n'); % Split input into rows based on new lines
        
            % Determine if the input has multiple rows and columns
            numRows = length(rows);
            numCols = cellfun(@(x) length(split(x, {',', '\t', ' '})), rows);
        
            % If the number of columns is inconsistent or more than one column and one row, show an error
            if numRows > 1 && any(numCols > 1)
                uialert(app.UIFigure, 'Invalid input format. Please enter either a row or a column vector. Matrix input is not accepted.', 'Input Error');
                return;
            end
        
            % Flatten the input into a single array of entries
            dataEntries = split(userInput, {',', '\n', '\t', ' '});
            dataEntries = strtrim(dataEntries); % Trim any extra whitespace from each entry
            dataEntries(cellfun('isempty', dataEntries)) = []; % Remove empty cells
        
            % Determine if the data is intended to be a number or a string
            selectedButton = app.VariableSelectorButtonGroup.SelectedObject;
        
            % Determine which column to add the data to based on the selected button
            if selectedButton == app.ComponentButton
                colIdx = 1;
                isNumericData = false; % Component name is expected as a string
            elseif selectedButton == app.CASButton
                colIdx = 2;
                isNumericData = false; % CAS is expected as a string (it can be formatted like "50-00-0")
            elseif selectedButton == app.criticalTemperature_KButton
                colIdx = 3;
                isNumericData = true;
            elseif selectedButton == app.criticalPressure_kPaButton
                colIdx = 4;
                isNumericData = true;
            elseif selectedButton == app.saturation_temperature_KButton
                colIdx = 5;
                isNumericData = true;
            elseif selectedButton == app.saturation_pressure_kPaButton
                colIdx = 6;
                isNumericData = true;
            elseif selectedButton == app.enthalpy_vaporization_temperature_KButton
                colIdx = 7;
                isNumericData = true;
            elseif selectedButton == app.enthalpy_vaporization_kJ_per_molButton
                colIdx = 8;
                isNumericData = true;
            elseif selectedButton == app.cp_temperature_KButton
                colIdx = 9;
                isNumericData = true;
            elseif selectedButton == app.cp_J_per_K_molButton
                colIdx = 10;
                isNumericData = true;
            else
                uialert(app.UIFigure, 'Please select a variable to enter data.', 'Selection Error');
                return;
            end
        
            % Validate and convert data entries
            if isNumericData
                % Convert all entries to numeric values
                numericValues = str2double(dataEntries);
                if any(isnan(numericValues))
                    uialert(app.UIFigure, 'Please enter valid numeric values.', 'Input Error');
                    return;
                end
                dataToAdd = num2cell(numericValues); % Convert to cell array for table
            else
                % If the data should be strings, make sure all entries are valid strings
                dataToAdd = cellfun(@char, dataEntries, 'UniformOutput', false); % Convert to cell array of strings
            end
        
            % Prepare new table data, replacing the entire selected column with new data
            currentData = app.dataDisplayTable.Data;
        
            % Ensure that currentData has the correct number of rows
            maxRows = max(length(dataToAdd), size(currentData, 1));
            if isempty(currentData)
                currentData = cell(maxRows, 10); % Initialize with empty cells to fit all columns
            elseif size(currentData, 1) < maxRows
                currentData{maxRows, 10} = []; % Expand to have enough rows
            end
        
            % Replace the selected column with new data
            for i = 1:maxRows
                if i <= length(dataToAdd)
                    currentData{i, colIdx} = dataToAdd{i};
                else
                    currentData{i, colIdx} = []; % Clear old data if the new input is shorter
                end
            end
        
            % Update the table with the new data
            app.dataDisplayTable.Data = currentData;
        
            % Clear the DataEntryTextArea after entering the data
            app.ManualDataEntryWindowTextArea.Value = '';
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            % Get the user's "Documents" folder path
            userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            if ~ispc
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            
            % Define the main data folder and the experimental subfolder
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
            experimentalFolder = fullfile(dataFolder, 'Experimental_Data');
            
            % Create folders if they don't exist
            if ~exist(dataFolder, 'dir')
                mkdir(dataFolder);
            end
            if ~exist(experimentalFolder, 'dir')
                mkdir(experimentalFolder);
            end
            
            % Extract the Component and CAS Number from the first row of the table
            tableData = app.dataDisplayTable.Data; % Extract the data from the table
            if isempty(tableData) || size(tableData, 1) < 1 || size(tableData, 2) < 2
                uialert(app.UIFigure, 'Table must have at least one row with Component and CAS data.', 'Save Error');
                return;
            end
            
            componentName = tableData{1, 1}; % First row, first column
            casNumber = tableData{1, 2}; % First row, second column
            
            % Validate that both Component and CAS are non-empty strings
            if isempty(componentName) || isempty(casNumber) || ~ischar(componentName) || ~ischar(casNumber)
                uialert(app.UIFigure, 'Component name and CAS number must be non-empty strings in the first row.', 'Save Error');
                return;
            end
            
            % Remove special characters from the Component Name
            modifiedComponentName = regexprep(componentName, '[^a-zA-Z0-9]', '');
            
            % Construct the filename using the modified component name and CAS number
            dataFileName = sprintf('%s_%s.csv', modifiedComponentName, casNumber);
            
            % Define the full path for the .csv file
            dataFilePath = fullfile(experimentalFolder, dataFileName);
            
            % Convert cell array data to a table for easy writing to CSV
            headers = app.dataDisplayTable.ColumnName; % Get the column headers
            tableDataCell = app.dataDisplayTable.Data; % Get the cell array data
            dataTable = cell2table(tableDataCell, 'VariableNames', headers);
            
            % Save the table data to a CSV file
            try
                writetable(dataTable, dataFilePath);
                % Inform the user that data has been saved successfully
                uialert(app.UIFigure, sprintf('Data has been saved successfully as "%s".', dataFileName), 'Save Successful');
            catch ME
                % Inform the user if there was an error saving the data
                uialert(app.UIFigure, sprintf('Failed to save data: %s', ME.message), 'Save Error');
            end

        end

        % Drop down opening function: ComponentDropDown_screener
        function ComponentDropDown_screenerOpening(app, event)
            % Get the selected data type from DataTypeDropDown
            selectedDataType = app.DataTypeDropDown_screener.Value;
        
            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Check if the specified folder exists
            if ~exist(dataFolder, 'dir')
                % Show an alert if the folder does not exist
                uialert(app.UIFigure, 'The specified data folder could not be found.', 'Folder Not Found');
                % Set ComponentDropDown to default value
                app.ComponentDropDown_screener.Items = {'No Files Available'};
                return;
            end
        
            % Get the list of CSV files in the specified folder
            filePattern = fullfile(dataFolder, '*.csv');
            files = dir(filePattern);
            fileNames = {files.name};
        
            % Update the ComponentDropDown items with the file names
            if isempty(fileNames)
                % If no files are found, provide a default option
                app.ComponentDropDown_screener.Items = {'No Files Available'};
            else
                app.ComponentDropDown_screener.Items = fileNames;
            end

        end

        % Drop down opening function: XAxisDropDown
        function XAxisDropDownOpening(app, event)
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown_screener.Value;
        
            % Get the folder specified by DataTypeDropDown
            selectedDataType = app.DataTypeDropDown_screener.Value;
        
            % Define the folder path where the files are stored
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the file exists
            if ~exist(fullFilePath, 'file')
                % Display warning if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');
                return;
            end
        
            % Read the data from the file (assuming a CSV format)
            opts = detectImportOptions(fullFilePath);
            tableData = readtable(fullFilePath, opts);
        
            % Extract column names from the loaded table
            columnHeaders = tableData.Properties.VariableNames;
        
            % Update the SelectionDropDown items with the column headers
            app.XAxisDropDown.Items = columnHeaders;
        end

        % Drop down opening function: YAxisDropDown
        function YAxisDropDownOpening(app, event)
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown_screener.Value;
        
            % Get the folder specified by DataTypeDropDown
            selectedDataType = app.DataTypeDropDown_screener.Value;
        
            % Define the folder path where the files are stored
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the file exists
            if ~exist(fullFilePath, 'file')
                % Display warning if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');
                return;
            end
        
            % Read the data from the file (assuming a CSV format)
            opts = detectImportOptions(fullFilePath);
            tableData = readtable(fullFilePath, opts);
        
            % Extract column names from the loaded table
            columnHeaders = tableData.Properties.VariableNames;
        
            % Update the SelectionDropDown items with the column headers
            app.YAxisDropDown.Items = columnHeaders;
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            % Set the selected tab in the TabGroup to ScreeningTab
            app.ScreeningProcessTab.SelectedTab = app.ManualTab;

        end

        % Button pushed function: RemoveMarkedOutliersButton
        function RemoveMarkedOutliersButtonPushed(app, event)
            % Get the current data in the screening table
            screeningData = app.table_screeningData.Data;
 
            % Get the plotted data from the UIAxes
            plottedData = app.UIAxes_ScreeningData.Children;
        
            % Check if there is only one set of X and Y values in the plot
            if length(plottedData) > 1
                uialert(app.UIFigure, 'You must first accept or reject the outliers before proceeding with manual screening.', 'Outlier Acceptance Required');
                return;
            end
            
            % Get the brushed data from the UIAxes
            % Assuming that 'app.UIAxes_ScreeningData' is used for the plot
            brushedData = get(app.UIAxes_ScreeningData.Children, 'BrushData');
        
            % Convert brushedData to a logical array
            brushedIndices = brushedData == 1;
        
            % Check if there is any data marked for deletion
            if isempty(brushedIndices) || ~any(brushedIndices)
                % If no data is brushed, show a warning message
                uialert(app.UIFigure, 'No points marked as outliers. Please use the brush tool to mark data points.', 'No Outliers Marked');
                return;
            end
        
            % Get the X and Y data from the screeningData
            xData = screeningData{:, 1};
            yData = screeningData{:, 2};
        
            % Remove the brushed points from the data
            xDataCleaned = xData(~brushedIndices);
            yDataCleaned = yData(~brushedIndices);
        
            % Create cleaned data tables for X and Y
            modifiedXLabel = app.XAxisDropDown.Value;
            modifiedXLabel = strrep(modifiedXLabel, '_', ' ');
        
            modifiedYLabel = app.YAxisDropDown.Value;
            modifiedYLabel = strrep(modifiedYLabel, '_', ' ');
        
            xColumnTable = table(xDataCleaned, 'VariableNames', {modifiedXLabel});
            yColumnTable = table(yDataCleaned, 'VariableNames', {modifiedYLabel});
        
            % Update the screening data table with the cleaned data
            updatedScreeningData = [xColumnTable, yColumnTable];
            app.table_screeningData.Data = updatedScreeningData;
        
            % Update the plot with the cleaned data
            scatter(app.UIAxes_ScreeningData, xDataCleaned, yDataCleaned);
        
            % Label the axes again after updating the plot
            xlabel(app.UIAxes_ScreeningData, modifiedXLabel);
            ylabel(app.UIAxes_ScreeningData, modifiedYLabel);
            title(app.UIAxes_ScreeningData, sprintf('Plot of %s vs %s', modifiedYLabel, modifiedXLabel));

        end

        % Button pushed function: CompleteManualScreeningButton
        function CompleteManualScreeningButtonPushed(app, event)
            % Set the selected tab in the TabGroup to HomeTab
            app.ScreeningProcessTab.SelectedTab = app.HomeTab;          
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)

    % Get the value selected in the ComponentDropDown (file name)
    selectedFile = app.ComponentDropDown_screener.Value;

    % Define the folder path where the files are stored
    if ispc
        userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
    else
        userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
    end

    % Get the selected data type from the DataTypeDropDown
    selectedDataType = app.DataTypeDropDown_screener.Value;

    % Define the main data folder and the screened data subfolder
    dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
    screenedDataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', 'Screened_Experimental_Data');

    % Create the folders if they do not exist
    if ~exist(screenedDataFolder, 'dir')
        mkdir(screenedDataFolder);
    end

    % Create the full path to the selected file
    fullFilePath = fullfile(dataFolder, selectedFile);
    
    % Create a new file name with '_screened' suffix if it doesn't already exist
    [~, name, ext] = fileparts(selectedFile);
    if endsWith(name, '_screened')
        screenedFileName = strcat(name, ext);
    else
        screenedFileName = strcat(name, '_screened', ext);
    end

    screenedFilePath = fullfile(screenedDataFolder, screenedFileName);

    % Check if the file exists
    if ~exist(screenedFilePath, 'file')
        % Read the original data from the unscreened CSV file
        opts = detectImportOptions(fullFilePath);
        originalData = readtable(fullFilePath, opts);
    
    else

    % Read the original data from the screened CSV file
    opts = detectImportOptions(screenedFilePath);
    originalData = readtable(screenedFilePath, opts);
    
    end

    % Get the current data from the screening data table
    screenedData = app.table_screeningData.Data;

    % Extract column names from dropdowns for X and Y
    selectedXColumn = app.XAxisDropDown.Value;
    selectedYColumn = app.YAxisDropDown.Value;

    % Find the index of the selected columns in the original data table
    xColumnIdx = find(strcmp(originalData.Properties.VariableNames, selectedXColumn));
    yColumnIdx = find(strcmp(originalData.Properties.VariableNames, selectedYColumn));

    % Check if columns exist in the original data
    if isempty(xColumnIdx) || isempty(yColumnIdx)
        uialert(app.UIFigure, 'Could not find the specified columns in the original file.', 'Column Error');
        return;
    end

    % Update only the specified columns with the screened data
    % Fill with NaN for any removed values to maintain size
    numOriginalRows = size(originalData, 1);
    numScreenedRows = size(screenedData, 1);

    % Initialize columns with NaN values
    updatedXColumnData = NaN(numOriginalRows, 1);
    updatedYColumnData = NaN(numOriginalRows, 1);

    % Copy the screened data to the appropriate rows
    updatedXColumnData(1:numScreenedRows) = table2array(screenedData(:, 1)); % X-axis data from screenedData
    updatedYColumnData(1:numScreenedRows) = table2array(screenedData(:, 2)); % Y-axis data from screenedData

    % Update the original data table with the screened data for the specified columns
    originalData{:, xColumnIdx} = updatedXColumnData;
    originalData{:, yColumnIdx} = updatedYColumnData;


    % Write the updated data to the new CSV file in the screened data folder
    writetable(originalData, screenedFilePath);

    % Display success message to user
    uialert(app.UIFigure, 'Screened data saved successfully in the Screened_Experimental_Data folder!', 'Save Success');

    % Re-plot the data to reflect the latest changes
    % PlotDataButtonPushed(app, event);


        end

        % Drop down opening function: DataTypeDropDown_screener
        function DataTypeDropDown_screenerOpening(app, event)

            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
        
            % Check if the TTP_GUI_Data folder exists
            if ~exist(dataFolder, 'dir')
                % If the folder does not exist, show an alert
                uialert(app.UIFigure, 'No data folder found. Please create or import data to proceed.', 'Warning: No Data Found');
                % Set the dropdown to default value since no folder is found
                app.DataTypeDropDown_screener.Items = {'No Data Available'};
                return;
            end
        
            % Get the list of subfolders within the TTP_GUI_Data folder
            folderInfo = dir(dataFolder);
            isSubFolder = [folderInfo.isdir] & ~ismember({folderInfo.name}, {'.', '..'});
            subFolders = {folderInfo(isSubFolder).name};
        
            % Update the DataTypeDropDown items with the folder names
            if isempty(subFolders)
                % If there are no subfolders, show an alert and provide a default option
                uialert(app.UIFigure, 'No data folders found within TTP_GUI_Data. Please add data to proceed.', 'Warning: No Data Found');
                app.DataTypeDropDown_screener.Items = {'No Data Available'};
            else
                % Use the folder names as items in the dropdown
                app.DataTypeDropDown_screener.Items = subFolders;
            end

        end

        % Button pushed function: ToComponentManagerButton
        function ToComponentManagerButtonPushed(app, event)
            % Set the selected tab in the TabGroup to ScreeningTab
            app.TabGroup.SelectedTab = app.ComponentManagerTab;
        end

        % Button pushed function: AutoViaRegressionTreesButton
        function AutoViaRegressionTreesButtonPushed(app, event)
            % Set the selected tab in the TabGroup to ScreeningTab
            app.ScreeningProcessTab.SelectedTab = app.AutoTab;
        end

        % Button pushed function: StartAutoScreeningButton
        function StartAutoScreeningButtonPushed(app, event)
        
            % Extract the name from ComponentDropDown (text before the first underscore or dash)
            fullComponentName = app.ComponentDropDown_screener.Value;
            splitName = regexp(fullComponentName, '[_-]', 'split');
            name = splitName{1};
        
            % Extract variable names for x-axis and y-axis from dropdowns
            xAxisName = app.XAxisDropDown.Value;
            yAxisName = app.YAxisDropDown.Value;
            var_names = {xAxisName, yAxisName}; % Row vector containing names for x and y
        
            % Set tau and other parameters
            tau = [0.25, 0.5, 0.75]; % Represents the 25th, 50th (median), and 75th quartiles needed for the regression
            NumTrees = app.NumberofTreesEditField.Value; 
            binSize = app.BinningSizeEditField.Value; 
            k = app.MultiplierfortheIQRFenceEditField.Value;
            plotAll = false; % Set plotAll to false
            Tbl = app.table_screeningData.Data;
        
            % Construct the dynamic file path using genpath to add all subfolders
            appFolderPath = fileparts(mfilename('fullpath'));
            addpath(genpath(appFolderPath));
        
            [screenedData,detectedOutlines,limits] = removeOutliers( Tbl, NumTrees, tau, binSize, k, plotAll, name, var_names);
        
        
            % Clear existing plot
            cla(app.UIAxes_ScreeningData);
            hold(app.UIAxes_ScreeningData, 'on');
        
            % Plot screened data as blue points
            plot(app.UIAxes_ScreeningData, screenedData{:, 1}, screenedData{:, 2}, Marker = "o", MarkerSize=8, LineStyle = "none",Color = "#88CCEE",LineWidth=1);
            
        
            % Plot detected outliers as red asterisks
            if ~isempty(detectedOutlines)
                plot(app.UIAxes_ScreeningData, detectedOutlines{:, 1}, detectedOutlines{:, 2}, Marker = "*", MarkerSize=8, LineStyle = "none",Color = "#882255",LineWidth=1);
            end 
        
            % Plot limits as lines (using temperature as the x-axis)
            if ~isempty(limits)
                plot(app.UIAxes_ScreeningData, limits{:, 1}, limits{:, 2}, Marker = "none", MarkerSize=8, LineStyle = "--",Color = "#44AA99",LineWidth=1.5); % Lower limit
                plot(app.UIAxes_ScreeningData, limits{:, 1}, limits{:, 3}, Marker = "none", MarkerSize=8, LineStyle = ":" ,Color = "#332288",LineWidth=1.5); % Upper limit
            end
        
            % Modify the axis labels
            xlabel(app.UIAxes_ScreeningData, strrep(xAxisName, '_', ' '));
            ylabel(app.UIAxes_ScreeningData, strrep(yAxisName, '_', ' '));
            title(app.UIAxes_ScreeningData, 'Screening Data with Detected Outliers');
        
            hold(app.UIAxes_ScreeningData, 'off');

            % Update the table_screeningData with screenedData
            app.table_screeningData.Data = screenedData;

        end

        % Button pushed function: AcceptOutliersButton
        function AcceptOutliersButtonPushed(app, event)
            %This function takes the current screen data and stores it within 
            % the table in such a way that the currently screened data is treated 
            % as the raw data so that we can either screen the data again
            % or move on to save.
            
            % Setting the original data set (or last Screened data set) to be the new data source
            newSourceData = app.table_screeningData.Data; 

            % Plot Accepted data points as blue points
            plot(app.UIAxes_ScreeningData, newSourceData{:, 1}, newSourceData{:, 2}, Marker = "o", MarkerSize=8, LineStyle = "none",Color = "#88CCEE",LineWidth=1);
            
            % Storing the original data set (or last Screened data set) to be the new data source
            app.table_sourceData.Data = newSourceData;
        end

        % Button pushed function: RejectOutliersButton
        function RejectOutliersButtonPushed(app, event)
            %This function effectively restores the plots and data to the 
            % prior setof accepted data or the original set of data in 
            % the event that no data has yet to be accepted
            
            % Setting the original data set (or last source data set) to be the new data source
            oldSourceData = app.table_sourceData.Data; 

            % Setting the original data set (or last source data set) to be the new screened data 
            app.table_screeningData.Data = oldSourceData;

             % Plot the original data set (or last source data set)  points as blue points
            plot(app.UIAxes_ScreeningData, oldSourceData{:, 1}, oldSourceData{:, 2}, Marker = "o", MarkerSize=8, LineStyle = "none",Color = "#88CCEE",LineWidth=1);

        end

        % Button pushed function: CompleteScreeningButton
        function CompleteScreeningButtonPushed(app, event)
            % Set the selected tab in the TabGroup to HomeTab
            app.ScreeningProcessTab.SelectedTab = app.HomeTab; 
        end

        % Drop down opening function: DataTypeDropDown_optimizer
        function DataTypeDropDown_optimizerOpening(app, event)

            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
        
            % Check if the TTP_GUI_Data folder exists
            if ~exist(dataFolder, 'dir')
                % If the folder does not exist, show an alert
                uialert(app.UIFigure, 'No data folder found. Please create or import data to proceed.', 'Warning: No Data Found');
                % Set the dropdown to default value since no folder is found
                app.DataTypeDropDown_plotter_Twu.Items = {'No Data Available'};
                return;
            end
        
            % Get the list of subfolders within the TTP_GUI_Data folder
            folderInfo = dir(dataFolder);
            isSubFolder = [folderInfo.isdir] & ~ismember({folderInfo.name}, {'.', '..'});
            subFolders = {folderInfo(isSubFolder).name};
        
            % Update the DataTypeDropDown items with the folder names
            if isempty(subFolders)
                % If there are no subfolders, show an alert and provide a default option
                uialert(app.UIFigure, 'No data folders found within TTP_GUI_Data. Please add data to proceed.', 'Warning: No Data Found');
                app.DataTypeDropDown_plotter_Twu.Items = {'No Data Available'};
            else
                % Use the folder names as items in the dropdown
                app.DataTypeDropDown_plotter_Twu.Items = subFolders;
            end

        end

        % Drop down opening function: ComponentDropDown_optimizer
        function ComponentDropDown_optimizerOpening(app, event)
            % Get the selected data type from DataTypeDropDown
            selectedDataType = app.DataTypeDropDown_optimizer.Value;
        
            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Check if the specified folder exists
            if ~exist(dataFolder, 'dir')
                % Show an alert if the folder does not exist
                uialert(app.UIFigure, 'The specified data folder could not be found.', 'Folder Not Found');
                % Set ComponentDropDown to default value
                app.ComponentDropDown_optimizer.Items = {'No Files Available'};
                return;
            end
        
            % Get the list of CSV files in the specified folder
            filePattern = fullfile(dataFolder, '*.csv');
            files = dir(filePattern);
            fileNames = {files.name};
        
            % Update the ComponentDropDown items with the file names
            if isempty(fileNames)
                % If no files are found, provide a default option
                app.ComponentDropDown_optimizer.Items = {'No Files Available'};
            else
                app.ComponentDropDown_optimizer.Items = fileNames;
            end

        end

        % Button pushed function: ToOptimizerButton
        function ToOptimizerButtonPushed(app, event)
            % Set the selected tab in the TabGroup to OptimizerTab
            app.TabGroup.SelectedTab = app.OptimizerTab;
        end

        % Value changed function: AlphaFunctionDropDown
        function AlphaFunctionDropDownValueChanged(app, event)
            value = app.AlphaFunctionDropDown.Value;
                
            displayType = app.DisplayOptionButtonGroup.SelectedObject.Text;

            if value == "Soave"
                % Set the selected tab in the TabGroup to SoaveTab
                app.TabGroup_alphaFunction.SelectedTab = app.SoaveTab;
                if displayType == "Output Table"
                 app.TabGroup_OptimizationOutputs.SelectedTab = app.SoaveOutputTab;
                end
            elseif value =="Twu"
                % Set the selected tab in the TabGroup to TwuTab
                app.TabGroup_alphaFunction.SelectedTab = app.TwuTab;
                if displayType == "Output Table"
                    app.TabGroup_OptimizationOutputs.SelectedTab = app.TwuOutputTab;
                end
            end
            
        end

        % Button pushed function: ToDataScreeningButton_2
        function ToDataScreeningButton_2Pushed(app, event)
            ToDataScreeningButtonPushed(app,event)
        end

        % Button pushed function: AddWeighingFactorsButton
        function AddWeighingFactorsButtonPushed(app, event)
            % Get the data entered by the user
            userInput = app.ManualEntryWindow_weightingFactor.Value; % Get input as a string
        
            % Split the user input based on delimiters (commas, new lines, spaces, tabs)
            rows = split(userInput, '\n'); % Split input into rows based on new lines
        
            % Determine if the input has multiple rows and columns
            numRows = length(rows);
            numCols = cellfun(@(x) length(split(x, {',', '\t', ' '})), rows);
        
            % If the number of columns is inconsistent or more than one column and one row, show an error
            if numRows > 1 && any(numCols > 1)
                uialert(app.UIFigure, 'Invalid input format. Please enter either a row or a column vector. Matrix input is not accepted.', 'Input Error');
                return;
            end
        
            % Flatten the input into a single array of entries
            dataEntries = split(userInput, {',', '\n', '\t', ' '});
            dataEntries = strtrim(dataEntries); % Trim any extra whitespace from each entry
            dataEntries(cellfun('isempty', dataEntries)) = []; % Remove empty cells
        
            % Determine if the data is intended to be a number or a string
            selectedButton = app.WeighingFactorVariablesButtonGroup.SelectedObject;
        
            % Determine which column to add the data to based on the selected button
            if selectedButton == app.W_PButton
                colIdx = 1;
                isNumericData = true; 
            elseif selectedButton == app.W_CpButton
                colIdx = 2;
                isNumericData = true;
            elseif selectedButton == app.W_HButton
                colIdx = 3;
                isNumericData = true;

            else
                uialert(app.UIFigure, 'Please select a variable to enter data.', 'Selection Error');
                return;
            end
        
            % Validate and convert data entries
            if isNumericData
                % Convert all entries to numeric values
                numericValues = str2double(dataEntries);
                if any(isnan(numericValues))
                    uialert(app.UIFigure, 'Please enter valid numeric values.', 'Input Error');
                    return;
                end
                dataToAdd = num2cell(numericValues); % Convert to cell array for table
            else
                % If the data should be strings, make sure all entries are valid strings
                dataToAdd = cellfun(@char, dataEntries, 'UniformOutput', false); % Convert to cell array of strings
            end
        
            % Prepare new table data, replacing the entire selected column with new data
            currentData = app.UITable_weightingFactorSet.Data;
        
            % Ensure that currentData has the correct number of rows
            maxRows = max(length(dataToAdd), size(currentData, 1));
            if isempty(currentData)
                currentData = cell(maxRows, 3); % Initialize with empty cells to fit all columns
            elseif size(currentData, 1) < maxRows
                currentData{maxRows, 3} = []; % Expand to have enough rows
            end
        
            % Replace the selected column with new data
            for i = 1:maxRows
                if i <= length(dataToAdd)
                    currentData{i, colIdx} = dataToAdd{i};
                else
                    currentData{i, colIdx} = []; % Clear old data if the new input is shorter
                end
            end
        
            % Update the table with the new data
            app.UITable_weightingFactorSet.Data = currentData;
        
            % Clear the DataEntryTextArea after entering the data
            app.ManualEntryWindow_weightingFactor.Value = '';
        end

        % Button pushed function: ClearWeighingFactorsSetButton
        function ClearWeighingFactorsSetButtonPushed(app, event)
            % Set the UITable's Data property to an empty cell array to clear the table
            app.UITable_weightingFactorSet.Data = {};            
        end

        % Button pushed function: SaveWeightingFactorSetsButton
        function SaveWeightingFactorSetsButtonPushed(app, event)
            % Get the user's "Documents" folder path
            userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            if ~ispc
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            
            % Define the main data folder and the experimental subfolder
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
            WeightingFactorSets = fullfile(dataFolder, 'Weighting_Factor_Sets');
            
            % Create folders if they don't exist
            if ~exist(dataFolder, 'dir')
                mkdir(dataFolder);
            end
            if ~exist(WeightingFactorSets, 'dir')
                mkdir(WeightingFactorSets);
            end
                        
            weightingFactorSetName = app.SetNameEditField.Value;
            
            % Validate that both the name of the weighing factors set are non-empty strings
            if isempty(weightingFactorSetName) || ~ischar(weightingFactorSetName)
                uialert(app.UIFigure, 'Name of the weighing factors set must be non-empty strings (name for set was not given).', 'Save Error');
                return;
            end


            % Remove special characters from the Component Name
            modifiedweightingFactorSetName = regexprep(weightingFactorSetName, '[^a-zA-Z0-9]', '');
            
            % Construct the filename using the modified weighting factor set name
            dataFileName =  char(string(modifiedweightingFactorSetName)+".csv");
            
            % Define the full path for the .csv file
            dataFilePath = fullfile(WeightingFactorSets, dataFileName);
            
            % Convert cell array data to a table for easy writing to CSV
            headers = app.UITable_weightingFactorSet.ColumnName; % Get the column headers
            tableDataCell = app.UITable_weightingFactorSet.Data; % Get the cell array data

            % Validate that table is non-empty
            if isempty(tableDataCell) || ~ischar(tableDataCell)
                uialert(app.UIFigure, 'Can not save empty table, please add weights.', 'Save Error');
                return;
            end            
            dataTable = cell2table(tableDataCell, 'VariableNames', headers);
            
            % Save the table data to a CSV file
            try
                writetable(dataTable, dataFilePath);
                % Inform the user that data has been saved successfully
                uialert(app.UIFigure, sprintf('Data has been saved successfully as "%s".', dataFileName), 'Save Successful');
            catch ME
                % Inform the user if there was an error saving the data
                uialert(app.UIFigure, sprintf('Failed to save data: %s', ME.message), 'Save Error');
            end
            
        end

        % Button pushed function: ImportWeightsButton
        function ImportWeightsButtonPushed(app, event)
            % Open file selection dialog for CSV files
            [fileName, filePath] = uigetfile('*.csv', 'Select a CSV File to Import');
            
            % Check if the user did not cancel the file selection
            if isequal(fileName, 0)
                % User canceled the file selection
                return;
            end
        
            % Construct the full file path
            fullFilePath = fullfile(filePath, fileName);
        
            % Read the CSV file into a table
            try
                importedTable = readtable(fullFilePath);
            catch
                % Alert the user if there was an error reading the CSV
                uialert(app.UIFigure, 'Failed to read the CSV file. Please make sure it is formatted correctly.', 'Read Error');
                return;
            end
        
            % Convert the imported table to a cell array
            importedData = table2cell(importedTable);
        
            % Update the UITable's data
            app.UITable_weightingFactorSet.Data = importedData;
        
            % Inform the user that the CSV file has been imported successfully
            uialert(app.UIFigure, 'Data has been successfully imported from the CSV file.', 'Import Successful');
        end

        % Button pushed function: StartOptimizationButton
        function StartOptimizationButtonPushed(app, event)
            % this ID represent this optimzation run
            randID =string(round((rand()*1000)));
            

            % this section validates the UITable_weightingFactorSet 
            
            % Get the data from the UITable_weightingFactorSet
            tableData = app.UITable_weightingFactorSet.Data;

            if isempty(tableData)
                uialert(app.UIFigure, 'The weighting factor set is empty. Please ensure all columns have at leaste one value. Use 0 to indicate no weight.', 'Input Error');
                return;
            end
        
            % Check if there are rows that are completely empty
            emptyRows = all(cellfun(@isempty, tableData), 2);
        
            % If there are any empty rows, remove them
            if any(emptyRows)
                tableData(emptyRows, :) = [];
            end
        
            % Update the table with the cleaned data
            app.UITable_weightingFactorSet.Data = tableData;
        
            % Check for any individual empty values or NaN values in the table
            if any(cellfun(@isempty, tableData), 'all') || any(cellfun(@(x) isnumeric(x) && isnan(x), tableData), 'all')
                % Display warning to the user
                uialert(app.UIFigure, 'The weighting factor set contains empty values or NaN values. Please ensure all cells are filled in. Use 0 to indicate no weight.', 'Input Error');
                return;
            end


            %--------------------------------------------------------------------
            % Clear the DataEntryTextArea after entering the data
            app.OptimizationProgressTextArea.Value = '';            
            
            app.StartOptimizationButton.Visible = "off"; % toggle the visiblity of the start button
            app.UITable_TwuOutput.Visible ="off"; % toggle the visiblity of the table
            app.UITable_SoaveOutput.Visible ="off"; % toggle the visiblity of the table
            app.OptimizingImage_bar_1.Visible = "on";% toggle the visiblity of the loading image bar
            app.OptimizingImage_bar_2.Visible = "on";% toggle the visiblity of the loading image bar
            app.OptimizingImage_bar_3.Visible = "on";% toggle the visiblity of the loading image bar
            app.OptimizingLabel.Visible ="on"; % toggle the visiblity of the loading text
            app.OptimizingImage_spin.Visible = "on"; % toggle the visiblity of the loading image spin
            drawnow; % To update the UI immediately
            
            %This The section of code is responsible for collecting data from stored files
            % Define the folder path based on the DataTypeDropDown selection
            selectedDataType = app.DataTypeDropDown_optimizer.Value;           
    
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown_optimizer.Value;
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the file exists
            if ~exist(fullFilePath, 'file')
                % Display warning if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Error');

                app.StartOptimizationButton.Visible = "on"; % toggle the visiblity of the start button
                app.UITable_TwuOutput.Visible ="on"; % toggle the visiblity of the table
                app.UITable_SoaveOutput.Visible ="on"; % toggle the visiblity of the table
                app.OptimizingImage_bar_1.Visible = "off";% toggle the visiblity of the loading image bar
                app.OptimizingImage_bar_2.Visible = "off";% toggle the visiblity of the loading image bar 
                app.OptimizingImage_bar_3.Visible = "off";% toggle the visiblity of the loading image bar 
                app.OptimizingLabel.Visible ="off"; % toggle the visiblity of the loading text
                app.OptimizingImage_spin.Visible = "off"; % toggle the visiblity of the loading image  
                drawnow; % To update the UI immediately
                return;
            end
        
            % Read the data from the file (assuming a CSV format)
            opts = detectImportOptions(fullFilePath);
            tableData = readtable(fullFilePath, opts); % obtained data from file
            ComponentInfo={tableData,nan,nan};% 2 & 3 Parameters are unused in GUI (key input 1 of 4 for optimizer)
            

            % Obtaining the current weighting factor set that is displayed on the table
            WeightingFactorSets = app.UITable_weightingFactorSet.Data;
            WeightingFactorSets = cell2table(WeightingFactorSets);

            % Checking which alpha function was selected by the user
            selected_alphaFunction=app.AlphaFunctionDropDown.Value;

            % Selecting the parameters to be optimized depending on which alpha function is selected by the user
            if selected_alphaFunction == "Twu"

                fittingParms = [app.Alpha1EditField.Value,app.Alpha2EditField.Value,app.Alpha3EditField.Value];
                app.Twu_RunIdentifierLabel.Text = randID;

                % Initialize table data storage
                tableData_Twu = cell(height(WeightingFactorSets), 12); % 12 columns as defined 

            elseif selected_alphaFunction == "Soave"
                selected_alphaFunction ="SOAVE-GN";             
                fittingParms = [app.mEditField.Value,0,0];
                app.Soave_RunIdentifierLabel.Text = randID;

                % Initialize table data storage
                tableData_Soave = cell(height(WeightingFactorSets), 10); % 10 columns as defined                
            else
                uialert(app.UIFigure, 'A valid alpha function was not selected.', 'File Error');

                app.StartOptimizationButton.Visible = "on"; % toggle the visiblity of the start button
                app.UITable_TwuOutput.Visible ="on"; % toggle the visiblity of the table
                app.UITable_SoaveOutput.Visible ="on"; % toggle the visiblity of the table
                app.OptimizingImage_bar_1.Visible = "off";% toggle the visiblity of the loading image bar 
                app.OptimizingImage_bar_2.Visible = "off";% toggle the visiblity of the loading image bar 
                app.OptimizingImage_bar_3.Visible = "off";% toggle the visiblity of the loading image bar 
                app.OptimizingLabel.Visible ="off"; % toggle the visiblity of the loading text
                app.OptimizingImage_spin.Visible = "off"; % toggle the visiblity of the loading image
                drawnow; % To update the UI immediately
            end
            
            if app.CEoSDropDown.Value == "Peng-Robinson"
                CEoS ="PR";
            elseif app.CEoSDropDown.Value == "Redlich-Kwong"
                CEoS ="RK";
            end

            % key input 2 of 4 for optimizer
            ThermodynamicModelInfo ={CEoS,selected_alphaFunction,fittingParms};

                  % Construct the dynamic file path using genpath to add all subfolders
            appFolderPath = fileparts(mfilename('fullpath'));
            addpath(genpath(appFolderPath));        
            
            % Set options with OutputFcn for real-time monitoring
            outputFcn = @(x, optimValues, state) optimizationOutputFunction(x, optimValues, state, app);
            options = optimoptions('fmincon', 'UseParallel', true, 'Display', 'iter', 'OutputFcn', outputFcn);
            
            evaluationProperties = [app.SaturationPressureCheckBox.Value,app.VaporizationEnthalpyCheckBox.Value,app.SaturatedLiquidHeatCapacityCheckBox.Value];
            for weightFactorLoop =1:height(WeightingFactorSets)
                weightFactor_set= WeightingFactorSets{weightFactorLoop,:};

                % event is used to check weighting factors for 0 values and
                % modify optimization evaluationProperties.
                if WeightingFactorSets{1,1} ==0 % in event that the P_W is set to 0
                    evaluationProperties(1) = 0; % we dont evaluate the pressure
                elseif WeightingFactorSets{1,2} ==0 % in event that the Cp_W is set to 0
                    evaluationProperties(2) = 0; % we dont evaluate the heat capacity
                elseif WeightingFactorSets{1,3} ==0 % in event that the H_W is set to 0
                    evaluationProperties(3) = 0; % we dont evaluate the vaporization enthalpy
                end
                % key input 3 of 4 for optimizer
                OptimizationOptionsVector = {app.ApplyLeGuennecConstraintsCheckBox.Value,weightFactor_set,app.CpTrUpperLimitEditField.Value,evaluationProperties,app.ErrorFunctionDropDown.Value};

                global agg_error;
                agg_error = []; 
                
                tic
              
                [alphaConstants(weightFactorLoop,:),fval(weightFactorLoop,1)]=Optimzer(ComponentInfo,ThermodynamicModelInfo,OptimizationOptionsVector,options);
                % initalAlphaValues=alphaConstants(weightFactorLoop,1:3);
                runTime(weightFactorLoop,1) = toc;

                    optimizationResults(weightFactorLoop).OptimizedAlphaValues = alphaConstants(weightFactorLoop, :);
                    optimizationResults(weightFactorLoop).ObjectiveValue = fval(weightFactorLoop);
                    optimizationResults(weightFactorLoop).Runtime = runTime(weightFactorLoop);
                    optimizationResults(weightFactorLoop).ErrorValues.Pressure = agg_error(1);
                    optimizationResults(weightFactorLoop).ErrorValues.Enthalpy = agg_error(2);
                    optimizationResults(weightFactorLoop).ErrorValues.Cp = agg_error(3);
        
                 % Call the helper function to collect data
                 dataTable = collectOptimizationDataToTable(ComponentInfo, ThermodynamicModelInfo, OptimizationOptionsVector, optimizationResults,selectedFile);
                       
                 backupFolderPath = fullfile(userDocsFolder, 'TTP_GUI_Data','optimizationResults','backedUpFiles');
                 optimizedDataPath = fullfile(userDocsFolder, 'TTP_GUI_Data','optimizationResults');
                    % Check and create optimized data directory if it does not exist
                    if ~exist(optimizedDataPath, 'dir')
                        mkdir(optimizedDataPath);
                    end                 
                 % Save progress dynamically after each run
                 saveOptimizationData(dataTable(weightFactorLoop, :), backupFolderPath,randID,app);


                % Assuming selected_alphaFunction is defined elsewhere in your app.
                if strcmp(selected_alphaFunction, "Twu")
                
                        % Extract optimization outputs for Twu model
                        alpha1 = alphaConstants(weightFactorLoop, 1);
                        alpha2 = alphaConstants(weightFactorLoop, 2);
                        alpha3 = alphaConstants(weightFactorLoop, 3);
                        fvalCurrent = fval(weightFactorLoop, 1);
                        runTimeCurrent = runTime(weightFactorLoop, 1);
                        errorP  = agg_error(1);
                        errorH  = agg_error(2);
                        errorCp = agg_error(3);
                
                        % Populate tableData_Twu
                        tableData_Twu{weightFactorLoop, 1} = weightFactorLoop; % run
                        tableData_Twu{weightFactorLoop, 2} = WeightingFactorSets{weightFactorLoop, 1}; % W_P
                        tableData_Twu{weightFactorLoop, 3} = WeightingFactorSets{weightFactorLoop, 2}; % W_Cp
                        tableData_Twu{weightFactorLoop, 4} = WeightingFactorSets{weightFactorLoop, 3}; % W_H
                        tableData_Twu{weightFactorLoop, 5} = alpha1; % alpha_1
                        tableData_Twu{weightFactorLoop, 6} = alpha2; % alpha_2
                        tableData_Twu{weightFactorLoop, 7} = alpha3; % alpha_3
                        tableData_Twu{weightFactorLoop, 8} = errorP; % error_P
                        tableData_Twu{weightFactorLoop, 9} = errorCp; % error_Cp
                        tableData_Twu{weightFactorLoop, 10} = errorH; % error_H
                        tableData_Twu{weightFactorLoop, 11} = fvalCurrent; % fval
                        tableData_Twu{weightFactorLoop, 12} = runTimeCurrent; % run_time

                
                    % Update the Twu Output table in the app
                    app.UITable_TwuOutput.Data = tableData_Twu;
                
                elseif strcmp(selected_alphaFunction, "SOAVE-GN")
                
                        % Extract optimization outputs for Soave model
                        mParameter = alphaConstants(weightFactorLoop, 1); % Soave's m parameter
                        fvalCurrent = fval(weightFactorLoop, 1);
                        runTimeCurrent = runTime(weightFactorLoop, 1);
                        errorP  = agg_error(1);
                        errorH  = agg_error(2);
                        errorCp = agg_error(3);
                
                        % Populate tableData_Soave
                        tableData_Soave{weightFactorLoop, 1} = weightFactorLoop; % run
                        tableData_Soave{weightFactorLoop, 2} = WeightingFactorSets{weightFactorLoop, 1}; % W_P
                        tableData_Soave{weightFactorLoop, 3} = WeightingFactorSets{weightFactorLoop, 2}; % W_Cp
                        tableData_Soave{weightFactorLoop, 4} = WeightingFactorSets{weightFactorLoop, 3}; % W_H
                        tableData_Soave{weightFactorLoop, 5} = mParameter; % m_parameter
                        tableData_Soave{weightFactorLoop, 6} = errorP; % error_P
                        tableData_Soave{weightFactorLoop, 7} = errorCp; % error_Cp
                        tableData_Soave{weightFactorLoop, 8} = errorH; % error_H
                        tableData_Soave{weightFactorLoop, 9} = fvalCurrent; % fval
                        tableData_Soave{weightFactorLoop, 10} = runTimeCurrent; % run_time
                
                    % Update the Soave Output table in the app
                    app.UITable_SoaveOutput.Data = tableData_Soave;
                end
                app.UITable_TwuOutput.Visible ="on"; % toggle the visiblity of the table
                app.UITable_SoaveOutput.Visible ="on"; % toggle the visiblity of the table
                app.OptimizingImage_bar_2.Visible = "off";% toggle the visiblity of the loading image bar
                app.OptimizingImage_bar_3.Visible = "off";% toggle the visiblity of the loading image bar 
                drawnow; % To update the UI immediately
            end

            app.StartOptimizationButton.Visible = "on"; % toggle the visiblity of the start button                  
            app.OptimizingLabel.Visible ="off"; % toggle the visiblity of the loading text
            app.OptimizingImage_spin.Visible = "off"; % toggle the visiblity of the loading image
            app.OptimizingImage_bar_1.Visible = "off";% toggle the visiblity of the loading image bar 
            drawnow; % To update the UI immediately

             % Inform the user that Optimization has been completed successfully
            uialert(app.UIFigure, sprintf('Optimization has been completed successfully'), 'Optimization Successful');           

            function [alphaConstants,fval]=Optimzer(ComponentInfo,ThermodynamicModelInfo,OptimizationOptionsVector,options)
        
        
            initialAlphaValues=ThermodynamicModelInfo{3};
            % % Construct the dynamic file path using genpath to add all subfolders
            % appFolderPath = fileparts(mfilename('fullpath'));
            % addpath(genpath(appFolderPath));            
        
        
            % Obtaining critical properties and ideal properties of component
            [Tc,Pc,CP_constants,mw,w,Tb]=CEoS_database_manager(ComponentInfo{1}.CAS(1));
        
        
            HeatCapacity_Temperature =rmmissing(ComponentInfo{1}.cp_temperature_K);
            HeatCapacity_ReduceTemperature = rmmissing(ComponentInfo{1}.cp_temperature_K)./Tc;
            HeatCapacity_experimentalValues=rmmissing(ComponentInfo{1}.cp_J_per_K_mol);
        
            Temperature = { rmmissing(ComponentInfo{1}.saturation_temperature_K),...
                            rmmissing(ComponentInfo{1}.enthalpy_vaporization_temperature_K)...
                            HeatCapacity_Temperature(HeatCapacity_ReduceTemperature<=OptimizationOptionsVector{3})};
        
            experimentalValues ={ rmmissing(ComponentInfo{1}.saturation_pressure_kPa),...
                                  rmmissing(ComponentInfo{1}.enthalpy_vaporization_kJ_per_mol)...
                                  HeatCapacity_experimentalValues(HeatCapacity_ReduceTemperature<=OptimizationOptionsVector{3})};
        
            % options = optimoptions('fmincon','UseParallel',true,'Display','iter');
            nonlcon=@(alphaConstants)LeGuennec_constraints(alphaConstants,ThermodynamicModelInfo{2},OptimizationOptionsVector{1});
            fun= @(alphaConstants)ObjectiveFunction(Temperature,experimentalValues,ThermodynamicModelInfo,OptimizationOptionsVector,Tc,Pc,alphaConstants,CP_constants);
            % [alphaConstants,fval]=fmincon(fun,initialAlphaValues,a,b,a_eq,b_eq,lb,ub,nonlcon,options);
            problem=createOptimProblem('fmincon','x0',initialAlphaValues,'objective',fun,'nonlcon',nonlcon,'options',options);
        
            [alphaConstants,fval ] = run(GlobalSearch,problem);
        
        
        end
        
        function [OF]= ObjectiveFunction(Temperature,experimentalValues,ThermodynamicModelInfo,OptimizationOptionsVector,Tc,Pc,alphaConstants,CP_constants)
            % Construct the dynamic file path using genpath to add all subfolders
            appFolderPath = fileparts(mfilename('fullpath'));
            addpath(genpath(appFolderPath));         
            %Tags for volume translation
            optimizing_c=false;
            c_opt=0;
        
            % creating a vector containing pure comp properties; used to solve EOS
            [VECTOR_PROPERTIES_PURE_COMP]= PURE_COMP_PROPERTIES(Tc,Pc,alphaConstants,CP_constants);
            VECTOR_PROPERTIES_PURE_COMP(13:14)=[c_opt,optimizing_c];
        
            % 
            % [Psat_calc,~,~,~]   = thermo_prop_calc(ThermodynamicModelInfo{1},ThermodynamicModelInfo{2},"Psat",Temperature{1},ComponentInfo{1}.CAS(1),VECTOR_PROPERTIES_PURE_COMP);
            % [Venth_calc,~,~,~]  = thermo_prop_calc(ThermodynamicModelInfo{1},ThermodynamicModelInfo{2},"Venth",Temperature{2},ComponentInfo{1}.CAS(1),VECTOR_PROPERTIES_PURE_COMP);
            % [Cp_calc,~,~,~]     = thermo_prop_calc(ThermodynamicModelInfo{1},ThermodynamicModelInfo{2},"Cp",Temperature{2},ComponentInfo{1}.CAS(1),VECTOR_PROPERTIES_PURE_COMP);
        
            CEoS=ThermodynamicModelInfo{1};
            alphaFunction =ThermodynamicModelInfo{2};
            evaluationProperties=OptimizationOptionsVector{4};
            Psat_temp  = Temperature{1};
            Venth_temp = Temperature{2};
            Cp_temp    = Temperature{3};
        
            if evaluationProperties(1)
                parfor i=1:length(Psat_temp)
                    % Calculating the saturation pressure for the current temperature value
                    [Psat_calc(i,1), ~, ~]=SATURATION_PRESSURE_PURE_COMP(CEoS,alphaFunction,Psat_temp(i),0.0001,VECTOR_PROPERTIES_PURE_COMP);
                end
            else
                Psat_calc =[];
            end
        
            if evaluationProperties(2)
                parfor j=1:length(Venth_temp)
                    % Calculating the saturation pressure for the current temperature value
                    [Psat_Venth, Zliq_Venth, Zvap_Venth]=SATURATION_PRESSURE_PURE_COMP(CEoS,alphaFunction,Venth_temp(j),0.0001,VECTOR_PROPERTIES_PURE_COMP);
        
        
                    % Calculating the enthalpy of vaporization for the current temperature value  
                    [Venth_calc(j,1)]=ENTHALPY_VAPORIZATION_PURE_COMPS(CEoS,alphaFunction,Zliq_Venth,Zvap_Venth, Venth_temp(j),Psat_Venth,VECTOR_PROPERTIES_PURE_COMP);
        
                end
            else
                Venth_calc=[];
            end
        
            if evaluationProperties(3) 
            parfor k=1:length(Cp_temp)
                % Calculating the saturation pressure for the current temperature value
                [Psat_Cp, Zliq_Cp, ~]=SATURATION_PRESSURE_PURE_COMP(CEoS,alphaFunction,Cp_temp(k),0.0001,VECTOR_PROPERTIES_PURE_COMP);
        
                    % Calculating the heat capacity for the current temperature value  
                [Cp_calc(k,1)]=HEAT_CAPACITY_PURE_COMPS(CEoS,alphaFunction,Zliq_Cp, Cp_temp(k),Psat_Cp,VECTOR_PROPERTIES_PURE_COMP);
            end
            else
                Cp_calc=[];
            end
        
        
            if evaluationProperties(1)
                [~,Psat_agg_error]  = error_calc(OptimizationOptionsVector{5}, experimentalValues{1},Psat_calc);
            else
                Psat_agg_error = 0;
            end
        
            if evaluationProperties(2)
                [~,Venth_agg_error] = error_calc(OptimizationOptionsVector{5}, experimentalValues{2},Venth_calc);
            else
                Venth_agg_error = 0;
            end
        
            if evaluationProperties(3)
                [~,Cp_agg_error]    = error_calc(OptimizationOptionsVector{5}, experimentalValues{3},Cp_calc);
            else
                Cp_agg_error = 0;
            end
        
            Psat_Wf =OptimizationOptionsVector{1,2}(1);
            Venth_Wf=OptimizationOptionsVector{1,2}(1);
            Cp_Wf   =OptimizationOptionsVector{1,2}(1);
        
            OF = (Psat_Wf*Psat_agg_error+Venth_Wf*Venth_agg_error+Cp_Wf*Cp_agg_error)^2;
            agg_error =[Psat_agg_error,Venth_agg_error,Cp_agg_error];
        end
        
        %******* NON LINEAR CONSTRAINS (FROM GUENNEC PAPER)*************************
        function [c,ceq]=LeGuennec_constraints(alphaConstants,alphaFunction,apply_LeGuennec_constraints)
            if apply_LeGuennec_constraints == true
                if alphaFunction == "Twu"
                    twu_1= alphaConstants(1);
                    twu_2=alphaConstants(2);
                    twu_3=alphaConstants(3);
        
                    X=-3*(twu_3+twu_1-1);
                    Y=(twu_3^2)+(3*twu_1*twu_3)-3*twu_3+3*(twu_1^2)-6*twu_1+2;
                    Z=-twu_1*((twu_1^2)-3*twu_1+2);
        
                    c(1)= twu_1;
                    c(2)=-twu_2*twu_3;
                    c(3)=-(1-2*twu_1+2*sqrt(twu_1*(twu_1-1)))+twu_3;
                    c(4)= -(4*(Y^3)+4*Z*(X^3)+27*(Z^2)-18*X*Y*Z-((X^2)*(Y^2)));
        
                    ceq=[];            
                elseif alphaFunction == "Soave" ||alphaFunction == "SOAVE-GN" 
                    m= alphaConstants(1);
        
                    c=-m*(1+m);
                    ceq=[];
                end
            else
                c=[];
                ceq=[];
            end 
        
        end

% Output function to capture optimization progress and display in the TextArea
function stop = optimizationOutputFunction(x, optimValues, state, app)
    stop = false; % Continue optimization by default

    switch state
        case 'init'
            % Add headers for the log table when optimization starts
            tableHeader = sprintf('Iter\t\tf(x)\t\t\t\tFeasibility\t\tOptimality\t\tStep Size\n');
            separator = repmat('-', 1, 110);
            headerText = {tableHeader; separator};
            currentLog = app.OptimizationProgressTextArea.Value;
            app.OptimizationProgressTextArea.Value = [currentLog; headerText];
            drawnow;

        case 'iter'
            % Format the current iteration details to look like a table row with more spacing
            iterationDetails = sprintf('%3d\t\t%.6e\t\t%.3e\t\t%.3e\t\t%.3e', ...
                optimValues.iteration, optimValues.fval, optimValues.constrviolation, optimValues.firstorderopt, optimValues.stepsize);

            % Append the iteration details to the text area
            currentLog = app.OptimizationProgressTextArea.Value;
            app.OptimizationProgressTextArea.Value = [currentLog; {iterationDetails}];
            drawnow;

        case 'done'
            % Log end of optimization
            completionMessage = sprintf('\nEnd of Optimization.\n\n'); % Add new lines for spacing after optimization ends
            currentLog = app.OptimizationProgressTextArea.Value;
            app.OptimizationProgressTextArea.Value = [currentLog; {completionMessage}];
            drawnow;
    end
end

function [dataTable] = collectOptimizationDataToTable(ComponentInfo, ThermodynamicModelInfo, OptimizationOptionsVector, optimizationResults,dataFileName)
    % Helper function to collect all data related to an optimization run and save it into a table.
    % Inputs:
    % - ComponentInfo: Cell array with component-related information
    % - ThermodynamicModelInfo: Cell array with model info (e.g., alpha function, CEoS)
    % - OptimizationOptionsVector: Cell array with options like constraints, weights, etc.
    % - optimizationResults: Struct containing optimization results (different rows per weight factor set)

    % Unpack ComponentInfo
    componentData = ComponentInfo{1};
    componentName = componentData.Component{1}; % Extract the component name
    componentCAS = componentData.CAS{1}; % Extract the CAS number

    % Unpack ThermodynamicModelInfo
    CEoS = ThermodynamicModelInfo{1};
    alphaFunction = ThermodynamicModelInfo{2};
    fittingParameters = ThermodynamicModelInfo{3}; % Could be multiple values
    fittingParameters_1 = fittingParameters(1);
    fittingParameters_2 = fittingParameters(2);
    fittingParameters_3 = fittingParameters(3);


    % Unpack OptimizationOptionsVector
    applyConstraints = OptimizationOptionsVector{1};
    weightingFactors = OptimizationOptionsVector{2};
    CpTrUpperLimit = OptimizationOptionsVector{3};
    evaluationProperties = OptimizationOptionsVector{4};
    evaluationProperties_1 = evaluationProperties(1);
    evaluationProperties_2 = evaluationProperties(2);
    evaluationProperties_3 = evaluationProperties(3);
    errorFunction = OptimizationOptionsVector{5};

    % Extract results from optimizationResults
    numRuns = length(optimizationResults); % Number of weight factor sets used in optimization
    runNumbers = (1:numRuns)'; % Column vector for run numbers

    % Create columns for optimization settings that do not change across runs
    ceosCol = repmat({CEoS}, numRuns, 1);
    alphaFuncCol = repmat({alphaFunction}, numRuns, 1);
    constraintsCol = repmat(applyConstraints, numRuns, 1);
    cpTrUpperLimitCol = repmat(CpTrUpperLimit, numRuns, 1);
    evalPropCol_1 = repmat({evaluationProperties_1}, numRuns, 1);
    evalPropCol_2 = repmat({evaluationProperties_2}, numRuns, 1);
    evalPropCol_3 = repmat({evaluationProperties_3}, numRuns, 1);
    errorFuncCol = repmat({errorFunction}, numRuns, 1);
    dataFileName = repmat({string(dataFileName)}, numRuns, 1);
    fittingParameters_1 = repmat({fittingParameters_1}, numRuns, 1);
    fittingParameters_2 = repmat({fittingParameters_2}, numRuns, 1);
    fittingParameters_3 = repmat({fittingParameters_3}, numRuns, 1);


    % Create columns for component information (constant across runs)
    componentNameCol = repmat({componentName}, numRuns, 1);
    componentCASCol = repmat({componentCAS}, numRuns, 1);

    % Extract dynamic results and weighting factors
    % Initialize columns for alpha parameters and errors as separate columns instead of grouped
    alpha1Col = zeros(numRuns, 1);
    alpha2Col = zeros(numRuns, 1);
    alpha3Col = zeros(numRuns, 1);
    fvalCol = zeros(numRuns, 1);
    runTimeCol = zeros(numRuns, 1);
    errorPCol = zeros(numRuns, 1);
    errorHCol = zeros(numRuns, 1);
    errorCpCol = zeros(numRuns, 1);
    wPCol = zeros(numRuns, 1);
    wCpCol = zeros(numRuns, 1);
    wHCol = zeros(numRuns, 1);

    for i = 1:numRuns
        % Extract data from each optimization run
        alphaValues = optimizationResults(i).OptimizedAlphaValues;
        alpha1Col(i) = alphaValues(1);
        alpha2Col(i) = alphaValues(2);
        alpha3Col(i) = alphaValues(3);

        fvalCol(i) = optimizationResults(i).ObjectiveValue;
        runTimeCol(i) = optimizationResults(i).Runtime;

        % Extract error values
        errorValues = optimizationResults(i).ErrorValues;
        errorPCol(i) = errorValues.Pressure(1);
        errorHCol(i) = errorValues.Enthalpy(1);
        errorCpCol(i) = errorValues.Cp(1);


        % Extract weighting factors
        wPCol(i) = weightingFactors(1);
        wCpCol(i) = weightingFactors(2);
        wHCol(i) = weightingFactors(3);
    end

    % Create a unique RunID for each run based on ComponentName, CASNumber, and a timestamp
    timestamp = datetime('now', 'Format', 'yyyyMMdd_HHmmss');
    runIDCol = arrayfun(@(i) sprintf('%s_%s_Run%d_%s', ...
                  componentName, componentCAS, i, timestamp), runNumbers, 'UniformOutput', false);


    % Create a table with all the extracted information
    dataTable = table(runNumbers, runIDCol,dataFileName, componentNameCol, componentCASCol, ...
                      ceosCol, alphaFuncCol, fittingParameters_1, fittingParameters_2, fittingParameters_3, constraintsCol, ...
                      cpTrUpperLimitCol, evalPropCol_1,evalPropCol_2,evalPropCol_3, errorFuncCol, ...
                      wPCol, wCpCol, wHCol, alpha1Col, alpha2Col, alpha3Col, ...
                      errorPCol, errorHCol, errorCpCol, fvalCol, runTimeCol, ...
                      'VariableNames', {'Run', 'RunID','dataFile', 'ComponentName', 'CASNumber', 'CEoS', ...
                      'AlphaFunction', 'Alpha1_initalVal', 'Alpha2_initalVal', 'Alpha3_initalVal', ...
                      'ApplyConstraints', 'CpTrUpperLimit', 'Eval_P','Eval_Cp','Eval_H', 'ErrorFunction', ...
                      'Weight_P', 'Weight_Cp', 'Weight_H', 'Alpha1', 'Alpha2', 'Alpha3', ...
                      'Error_P', 'Error_H', 'Error_Cp', 'Fval', 'RunTime'});


end

function saveOptimizationData(dataTable, backupPath,randID,app)
    % saveOptimizationData - Function to dynamically back up and save optimization data.
    %
    % Inputs:
    %   dataTable         : Table containing all collected data from optimization.
    %   backupPath        : Directory path to store backup files during optimization runs.

    %   app               : Application object containing UI components.

    % Check and create backup directory if it does not exist
    if ~exist(backupPath, 'dir')
        mkdir(backupPath);
    end

    % Extract the values from the `dataTable`
    componentName = dataTable.ComponentName{1};
    casValue = dataTable.CASNumber{1};

    % Create a unique filename for backup, overwriting the previous backup for this run
    backupFileName = sprintf('backup_%s_%s_RunID_%s.csv', componentName, casValue, randID );
    backupFilePath = fullfile(backupPath, backupFileName);

    %saves the location of the backup file ( needed for Saving logic )
    if dataTable.AlphaFunction{1} == "SOAVE-GN"
        app.Soave_filePath.Text = backupFilePath;
    elseif dataTable.AlphaFunction{1} == "Twu"
        app.Twu_filePath.Text = backupFilePath;
    end

    % Save final optimization data
    if exist(backupFilePath, 'file')
        % Load the existing file and append the new data
        opts = detectImportOptions(backupFilePath);
        loadedData = readtable(backupFilePath, opts);

        % Convert both tables to cell arrays
        loadedDataCell = table2cell(loadedData);
        newDataCell = table2cell(dataTable);
        
        % Append the new rows to the existing data
        combinedDataCell = [loadedDataCell; newDataCell];
        
        % Convert back to table while ensuring that the column names remain the same
        combinedTable = cell2table(combinedDataCell, 'VariableNames', loadedData.Properties.VariableNames);
        
        % Write the combined table back to a CSV file
        writetable(combinedTable, backupFilePath);
    else
        % Write the table to the specified CSV file
        writetable(dataTable, backupFilePath);
    end
        

    end


        end

        % Selection changed function: DisplayOptionButtonGroup
        function DisplayOptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.DisplayOptionButtonGroup.SelectedObject.Text;

            if selectedButton == "Output Table"
                if app.AlphaFunctionDropDown.Value == "Soave"
                    % Set the selected tab in the TabGroup to SoaveTab
                    app.TabGroup_OptimizationOutputs.SelectedTab = app.SoaveOutputTab;

                elseif app.AlphaFunctionDropDown.Value =="Twu"

                    % Set the selected tab in the TabGroup to TwuTab
                    app.TabGroup_OptimizationOutputs.SelectedTab = app.TwuOutputTab;
                end
            elseif selectedButton == "Log"

                app.TabGroup_OptimizationOutputs.SelectedTab = app.LogTab;

            end

            
        end

        % Button pushed function: Soave_SaveOptimizationResultsButton
        function Soave_SaveOptimizationResultsButtonPushed(app, event)
        %getting the location of the back up file 
        backupFilePath = app.Soave_filePath.Text ;

        if ispc
            userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
        else
            userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
        end   
            
        optimizedResultsFolderPath = fullfile(userDocsFolder, 'TTP_GUI_Data','optimizationResults');
    
        % Check and create optimized data directory if it does not exist
        if ~exist(optimizedResultsFolderPath, 'dir')
            mkdir(optimizedResultsFolderPath);
        end
    
    
        % Save final optimization data
        if exist(backupFilePath, 'file')
            % Load the existing file and append the new data
            opts = detectImportOptions(backupFilePath);
            loadedData = readtable(backupFilePath, opts);
    
            % Convert both tables to cell arrays
            loadedDataCell = table2cell(loadedData);
    
            
            % Convert back to table while ensuring that the column names remain the same
            loadedTable = cell2table(loadedDataCell, 'VariableNames', loadedData.Properties.VariableNames);
            
    
        else
                % Write the table to the specified CSV file
                writetable(dataTable, backupFilePath);
                uialert(app.UIFigure,sprintf('Data not found, please run the optimizer'),'Runtime Error')
        end
    
        % Determine the final save filename
        if isempty(app.Soave_OptimizationSetNameEditField.Value) || strlength(app.Soave_OptimizationSetNameEditField.Value) == 0
    
            % Generate a unique name based on CAS value, component name, and randID 
            optimizedResultsFilePath =  strrep(backupFilePath, 'backedUpFiles\backup_', 'optimized_results_');
    
        else
    
            % Use user-provided name
            finalFileName = sprintf('optimized_results_%s.csv', app.Soave_OptimizationSetNameEditField.Value);
            optimizedResultsFilePath=fullfile (optimizedResultsFolderPath,finalFileName);
    
        end
    
        
        % Write the table to the specified CSV file
        writetable(loadedTable, optimizedResultsFilePath);
        end

        % Button pushed function: Twu_SaveOptimizationResultsButton
        function Twu_SaveOptimizationResultsButtonPushed(app, event)

        %getting the location of the back up file 
         backupFilePath = app.Twu_filePath.Text ;   

        if ispc
            userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
        else
            userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
        end   

        optimizedResultsFolderPath = fullfile(userDocsFolder, 'TTP_GUI_Data','optimizationResults');
    
        % Check and create optimized data directory if it does not exist
        if ~exist(optimizedResultsFolderPath, 'dir')
            mkdir(optimizedResultsFolderPath);
        end
    
    
        % Save final optimization data
        if exist(backupFilePath, 'file')
            % Load the existing file and append the new data
            opts = detectImportOptions(backupFilePath);
            loadedData = readtable(backupFilePath, opts);
    
            % Convert both tables to cell arrays
            loadedDataCell = table2cell(loadedData);
    
            
            % Convert back to table while ensuring that the column names remain the same
            loadedTable = cell2table(loadedDataCell, 'VariableNames', loadedData.Properties.VariableNames);
            
    
        else
                % Write the table to the specified CSV file
                writetable(dataTable, backupFilePath);
                uialert(app.UIFigure,sprintf('Data not found, please run the optimizer'),'Runtime Error')
        end
    
        % Determine the final save filename
        if isempty(app.Twu_OptimizationSetNameEditField.Value) || strlength(app.Twu_OptimizationSetNameEditField.Value) == 0
            % Generate a unique name based on CAS value, component name, and randID 
            optimizedResultsFilePath =  strrep(backupFilePath, 'backedUpFiles\backup_', 'optimized_results_');
    
        else
    
    
            % Use user-provided name
            finalFileName = sprintf('optimized_results_%s.csv', app.Soave_OptimizationSetNameEditField.Value);
            optimizedResultsFilePath=fullfile (optimizedResultsFolderPath,finalFileName);
    
        end 
    
        % Write the table to the specified CSV file
        writetable(loadedTable, optimizedResultsFilePath);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
    selectedTab = app.TabGroup.SelectedTab;

        % Check if the selected tab is the OptimizerTab
        if selectedTab == app.OptimizerTab
            % to start the parallel pool

            % Check if there is no parallel pool or if it has been shut down
            if isempty(gcp('nocreate'))
                % If there is no active parallel pool, start one
                parpool('IdleTimeout', 30); % Starts a parallel pool with a timeout of 30 minutes
            else
                disp('Parallel pool already active');
            end
        end

        end

        % Drop down opening function: DataTypeDropDown_plotter_Twu
        function DataTypeDropDown_plotter_Twu_Opening(app, event)
            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
        
            % Check if the TTP_GUI_Data folder exists
            if ~exist(dataFolder, 'dir')
                % If the folder does not exist, show an alert
                uialert(app.UIFigure, 'No data folder found. Please create or import data to proceed.', 'Warning: No Data Found');
                % Set the dropdown to default value since no folder is found
                app.DataTypeDropDown_plotter_Twu.Items = {'No Data Available'};
                return;
            end
        
            % Get the list of subfolders within the TTP_GUI_Data folder
            folderInfo = dir(dataFolder);
            isSubFolder = [folderInfo.isdir] & ~ismember({folderInfo.name}, {'.', '..'});
            subFolders = {folderInfo(isSubFolder).name};
        
            % Update the DataTypeDropDown items with the folder names
            if isempty(subFolders)
                % If there are no subfolders, show an alert and provide a default option
                uialert(app.UIFigure, 'No data folders found within TTP_GUI_Data. Please add data to proceed.', 'Warning: No Data Found');
                app.DataTypeDropDown_plotter_Twu.Items = {'No Data Available'};
            else
                % Use the folder names as items in the dropdown
                app.DataTypeDropDown_plotter_Twu.Items = subFolders;
            end
        end

        % Drop down opening function: ComponentDropDown_plotter_Twu
        function ComponentDropDown_plotter_Twu_Opening(app, event)
            % Get the selected data type from DataTypeDropDown
            selectedDataType = app.DataTypeDropDown_plotter_Twu.Value;
        
            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Check if the specified folder exists
            if ~exist(dataFolder, 'dir')
                % Show an alert if the folder does not exist
                uialert(app.UIFigure, 'The specified data folder could not be found.', 'Folder Not Found');
                % Set ComponentDropDown to default value
                app.ComponentDropDown_plotter_Twu.Items = {'No Files Available'};
                return;
            end
        
            % Get the list of CSV files in the specified folder
            filePattern = fullfile(dataFolder, '*.csv');
            files = dir(filePattern);
            fileNames = {files.name};
        
            % Update the ComponentDropDown items with the file names
            if isempty(fileNames)
                % If no files are found, provide a default option
                app.ComponentDropDown_plotter_Twu.Items = {'No Files Available'};
            else
                app.ComponentDropDown_plotter_Twu.Items = fileNames;
            end

        end

        % Selection changed function: AlphaParmatersSourceButtonGroup_Twu
        function AlphaParmatersSourceButtonGroup_TwuSelectionChanged(app, event)
            selectedButton = app.AlphaParmatersSourceButtonGroup_Twu.SelectedObject;

            if selectedButton == app.FromDataSetButton_Plotter_Twu
               % Set the selected tab in the TabGroup to DataSetTa
                app.TabGroup_Plotter_Twu.SelectedTab = app.DataSetTab_Plotter_Twu;
            elseif selectedButton == app.ManualEntryButton_Plotter_Twu
                % Set the selected tab in the TabGroup to ManualEntryButton
                app.TabGroup_Plotter_Twu.SelectedTab = app.ManualTab_Plotter_Twu;
            end

            
        end

        % Drop down opening function: RunIDDropDown_plotter_Twu
        function RunIDDropDown_plotter_TwuOpening(app, event)
          % Get the selected data type from DataTypeDropDown
            selectedDataType = app.DataTypeDropDown_plotter_Twu.Value;
        
            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Check if the specified folder exists
            if ~exist(dataFolder, 'dir')
                % Show an alert if the folder does not exist
                uialert(app.UIFigure, 'The specified data folder could not be found.', 'Folder Not Found');
                % Set RunIDDropDown to default value
                app.DataTypeDropDown_plotter_Twu.Items = {'No Runs Available'};
                return;
            end
        
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown_plotter_Twu.Value;
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the selected file exists
            if ~exist(fullFilePath, 'file')
                % Show an alert if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Not Found');
                % Set RunIDDropDown to default value
                app.DataTypeDropDown_plotter_Twu.Items = {'No Runs Available'};
                return;
            end
        
            % Read the data from the CSV file
            try
                opts = detectImportOptions(fullFilePath);
                dataTable = readtable(fullFilePath, opts);
            catch
                % If reading the file fails, show an alert
                uialert(app.UIFigure, 'Unable to read the selected file. Please ensure it is in the correct format.', 'File Read Error');
                % Set RunIDDropDown to default value
                app.DataTypeDropDown_plotter_Twu.Items = {'No Runs Available'};
                return;
            end
        
            % Check if "RunID" column exists in the data table
            if ~ismember('RunID', dataTable.Properties.VariableNames)
                % Show an alert if the RunID column does not exist
                uialert(app.UIFigure, 'The selected file does not contain a "RunID" column.', 'Invalid File Format');
                % Set RunIDDropDown to default value
                app.DataTypeDropDown_plotter_Twu.Items = {'No Runs Available'};
                return;
            end
        
            % Extract the RunID values from the RunID column
            runIDs = dataTable.RunID;
        
            % Check if runIDs are non-empty and valid
            if isempty(runIDs) || any(cellfun(@isempty, runIDs))
                % If no valid RunIDs are found, provide a default option
                app.RunIDDropDown_plotter_Twu.Items = {'No Runs Available'};
            else
                % Update the RunIDDropDown items with the extracted RunIDs
                app.RunIDDropDown_plotter_Twu.Items = runIDs;
            end
        end

        % Value changed function: RunIDDropDown_plotter_Twu
        function RunIDDropDown_plotter_TwuValueChanged(app, event)
            % Get the selected RunID from the RunID drop-down menu
            selectedRunID = app.RunIDDropDown_plotter_Twu.Value;
        
            % Define the folder path for TTP_GUI_Data
            selectedDataType = app.DataTypeDropDown_plotter_Twu.Value;
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
            
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown_plotter_Twu.Value;
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Read the data from the CSV file
            try
                opts = detectImportOptions(fullFilePath);
                dataTable = readtable(fullFilePath, opts);
            catch
                % If reading the file fails, show an alert
                uialert(app.UIFigure, 'Unable to read the selected file. Please ensure it is in the correct format.', 'File Read Error');
                return;
            end
        
            % Check if "RunID" column exists in the data table
            if ~ismember('RunID', dataTable.Properties.VariableNames)
                % Show an alert if the RunID column does not exist
                uialert(app.UIFigure, 'The selected file does not contain a "RunID" column.', 'Invalid File Format');
                return;
            end
        
            % Find the index of the selected RunID
            rowIndex = find(strcmp(dataTable.RunID, selectedRunID));
        
            % If no matching row is found, show an alert
            if isempty(rowIndex)
                uialert(app.UIFigure, 'The selected RunID was not found in the file.', 'Invalid RunID');
                return;
            end
        
            % Extract Alpha values for the selected RunID
            if ismember('Alpha1', dataTable.Properties.VariableNames) && ...
               ismember('Alpha2', dataTable.Properties.VariableNames) && ...
               ismember('Alpha3', dataTable.Properties.VariableNames)
           
                alpha1 = dataTable.Alpha1(rowIndex);
                alpha2 = dataTable.Alpha2(rowIndex);
                alpha3 = dataTable.Alpha3(rowIndex);
        
                % Create a table with the extracted alpha values
                alphaDataTable = table(alpha1, alpha2, alpha3, ...
                    'VariableNames', {'Alpha1', 'Alpha2', 'Alpha3'});
        
                % Update the UITable_alphaTable_Twu with the extracted values
                app.UITable_alphaTable_Twu.Data = alphaDataTable;
        
            else
                % If the Alpha columns do not exist, show an alert
                uialert(app.UIFigure, 'The selected file does not contain the required Alpha columns.', 'Missing Columns');
            end
            
        end

        % Button pushed function: UpdateParametersButton_Twu
        function UpdateParametersButton_TwuPushed(app, event)
            % Collecting manual entry data from respective user entry
            alpha1 = app.Alpha1EditField_plotter.Value;
            alpha2 = app.Alpha2EditField_plotter.Value;
            alpha3 = app.Alpha3EditField_plotter.Value;
            
            % Create a table with the extracted alpha values
            alphaDataTable = table(alpha1, alpha2, alpha3, ...
                'VariableNames', {'Alpha1', 'Alpha2', 'Alpha3'});

            % Update the UITable_alphaTable_Twu with the extracted values
            app.UITable_alphaTable_Twu.Data = alphaDataTable;

        end

        % Selection changed function: 
        % AlphaParametersSourceButtonGroup_Soave
        function AlphaParametersSourceButtonGroup_SoaveSelectionChanged(app, event)
            selectedButton = app.AlphaParametersSourceButtonGroup_Soave.SelectedObject;

            if selectedButton == app.FromDataSetButton_Plotter_Soave
               % Set the selected tab in the TabGroup to DataSetTa
                app.TabGroup_Plotter_Soave.SelectedTab = app.DataSetTab_Plotter_Soave;
            elseif selectedButton == app.ManualEntryButton_Plotter_Soave
                % Set the selected tab in the TabGroup to ManualEntryButton
                app.TabGroup_Plotter_Soave.SelectedTab = app.ManualTab_Plotter_Soave;

            elseif selectedButton == app.GeneralizedModelButton_Plotter_Soave
                % Set the selected tab in the TabGroup to Generalized Model Tab
                app.TabGroup_Plotter_Soave.SelectedTab = app.GeneralizedModelTab;
            end        
        end

        % Drop down opening function: DataTypeDropDown_plotter_Soave
        function DataTypeDropDown_plotter_SoaveOpening(app, event)
            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data');
        
            % Check if the TTP_GUI_Data folder exists
            if ~exist(dataFolder, 'dir')
                % If the folder does not exist, show an alert
                uialert(app.UIFigure, 'No data folder found. Please create or import data to proceed.', 'Warning: No Data Found');
                % Set the dropdown to default value since no folder is found
                app.DataTypeDropDown_plotter_Soave.Items = {'No Data Available'};
                return;
            end
        
            % Get the list of subfolders within the TTP_GUI_Data folder
            folderInfo = dir(dataFolder);
            isSubFolder = [folderInfo.isdir] & ~ismember({folderInfo.name}, {'.', '..'});
            subFolders = {folderInfo(isSubFolder).name};
        
            % Update the DataTypeDropDown items with the folder names
            if isempty(subFolders)
                % If there are no subfolders, show an alert and provide a default option
                uialert(app.UIFigure, 'No data folders found within TTP_GUI_Data. Please add data to proceed.', 'Warning: No Data Found');
                app.DataTypeDropDown_plotter_Soave.Items = {'No Data Available'};
            else
                % Use the folder names as items in the dropdown
                app.DataTypeDropDown_plotter_Soave.Items = subFolders;
            end            
        end

        % Drop down opening function: ComponentDropDown_plotter_Soave
        function ComponentDropDown_plotter_SoaveOpening(app, event)
            % Get the selected data type from DataTypeDropDown
            selectedDataType = app.DataTypeDropDown_plotter_Soave.Value;
        
            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Check if the specified folder exists
            if ~exist(dataFolder, 'dir')
                % Show an alert if the folder does not exist
                uialert(app.UIFigure, 'The specified data folder could not be found.', 'Folder Not Found');
                % Set ComponentDropDown to default value
                app.ComponentDropDown_plotter_Soave.Items = {'No Files Available'};
                return;
            end
        
            % Get the list of CSV files in the specified folder
            filePattern = fullfile(dataFolder, '*.csv');
            files = dir(filePattern);
            fileNames = {files.name};
        
            % Update the ComponentDropDown items with the file names
            if isempty(fileNames)
                % If no files are found, provide a default option
                app.ComponentDropDown_plotter_Soave.Items = {'No Files Available'};
            else
                app.ComponentDropDown_plotter_Soave.Items = fileNames;
            end
            
        end

        % Drop down opening function: RunIDDropDown_plotter_Soave
        function RunIDDropDown_plotter_SoaveOpening(app, event)
          % Get the selected data type from DataTypeDropDown
            selectedDataType = app.DataTypeDropDown_plotter_Soave.Value;
        
            % Define the folder path for TTP_GUI_Data
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
        
            % Check if the specified folder exists
            if ~exist(dataFolder, 'dir')
                % Show an alert if the folder does not exist
                uialert(app.UIFigure, 'The specified data folder could not be found.', 'Folder Not Found');
                % Set RunIDDropDown to default value
                app.DataTypeDropDown_plotter_Soave.Items = {'No Runs Available'};
                return;
            end
        
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown_plotter_Soave.Value;
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Check if the selected file exists
            if ~exist(fullFilePath, 'file')
                % Show an alert if the file does not exist
                uialert(app.UIFigure, 'The selected file could not be found.', 'File Not Found');
                % Set RunIDDropDown to default value
                app.DataTypeDropDown_plotter_Soave.Items = {'No Runs Available'};
                return;
            end
        
            % Read the data from the CSV file
            try
                opts = detectImportOptions(fullFilePath);
                dataTable = readtable(fullFilePath, opts);
            catch
                % If reading the file fails, show an alert
                uialert(app.UIFigure, 'Unable to read the selected file. Please ensure it is in the correct format.', 'File Read Error');
                % Set RunIDDropDown to default value
                app.DataTypeDropDown_plotter_Soave.Items = {'No Runs Available'};
                return;
            end
        
            % Check if "RunID" column exists in the data table
            if ~ismember('RunID', dataTable.Properties.VariableNames)
                % Show an alert if the RunID column does not exist
                uialert(app.UIFigure, 'The selected file does not contain a "RunID" column.', 'Invalid File Format');
                % Set RunIDDropDown to default value
                app.DataTypeDropDown_plotter_Soave.Items = {'No Runs Available'};
                return;
            end
        
            % Extract the RunID values from the RunID column
            runIDs = dataTable.RunID;
        
            % Check if runIDs are non-empty and valid
            if isempty(runIDs) || any(cellfun(@isempty, runIDs))
                % If no valid RunIDs are found, provide a default option
                app.RunIDDropDown_plotter_Soave.Items = {'No Runs Available'};
            else
                % Update the RunIDDropDown items with the extracted RunIDs
                app.RunIDDropDown_plotter_Soave.Items = runIDs;
            end     
        end

        % Value changed function: RunIDDropDown_plotter_Soave
        function RunIDDropDown_plotter_SoaveValueChanged(app, event)
            % Get the selected RunID from the RunID drop-down menu
            selectedRunID = app.RunIDDropDown_plotter_Soave.Value;
        
            % Define the folder path for TTP_GUI_Data
            selectedDataType = app.DataTypeDropDown_plotter_Soave.Value;
            if ispc
                userDocsFolder = fullfile(getenv('USERPROFILE'), 'Documents'); % For Windows
            else
                userDocsFolder = fullfile(getenv('HOME'), 'Documents'); % For macOS/Linux
            end
            dataFolder = fullfile(userDocsFolder, 'TTP_GUI_Data', selectedDataType);
            
            % Get the value selected in the ComponentDropDown (file name)
            selectedFile = app.ComponentDropDown_plotter_Soave.Value;
        
            % Create the full path to the selected file
            fullFilePath = fullfile(dataFolder, selectedFile);
        
            % Read the data from the CSV file
            try
                opts = detectImportOptions(fullFilePath);
                dataTable = readtable(fullFilePath, opts);
            catch
                % If reading the file fails, show an alert
                uialert(app.UIFigure, 'Unable to read the selected file. Please ensure it is in the correct format.', 'File Read Error');
                return;
            end
        
            % Check if "RunID" column exists in the data table
            if ~ismember('RunID', dataTable.Properties.VariableNames)
                % Show an alert if the RunID column does not exist
                uialert(app.UIFigure, 'The selected file does not contain a "RunID" column.', 'Invalid File Format');
                return;
            end
        
            % Find the index of the selected RunID
            rowIndex = find(strcmp(dataTable.RunID, selectedRunID));
        
            % If no matching row is found, show an alert
            if isempty(rowIndex)
                uialert(app.UIFigure, 'The selected RunID was not found in the file.', 'Invalid RunID');
                return;
            end
        
            % Extract Alpha values for the selected RunID
            if ismember('Alpha1', dataTable.Properties.VariableNames)
           
                alpha1 = dataTable.Alpha1(rowIndex);

        
                % Create a table with the extracted alpha values
                alphaDataTable = table(alpha1,'VariableNames', {'Alpha1'});
        
                % Update the UITable_alphaTable_Twu with the extracted values
                app.UITable_mParameterTable_Soave.Data = alphaDataTable;
        
            else
                % If the Alpha columns do not exist, show an alert
                uialert(app.UIFigure, 'The selected file does not contain the required Alpha columns.', 'Missing Columns');
            end
            
        end

        % Button pushed function: UpdateParametersButton_Soave
        function UpdateParametersButton_SoavePushed(app, event)
            % Collecting manual entry data from respective user entry
            mParameter = app.mParameterEditField.Value;
            
            % Create a table with the extracted alpha values
            mParameterDataTable = table(mParameter, 'VariableNames', {'mParameter'});

            % Update the UITable_alphaTable_Twu with the extracted values
            app.UITable_mParameterTable_Soave.Data = mParameterDataTable;  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ComponentManagerTab = uitab(app.TabGroup);
            app.ComponentManagerTab.Title = 'Component Manager';
            app.dataType = uitabgroup(app.ComponentManagerTab);
            app.RequiredTab = uitab(app.dataType);
            app.RequiredTab.Title = 'Required';
            app.dataDisplayTable = uitable(app.RequiredTab);
            app.dataDisplayTable.ColumnName = {'Component'; 'CAS'; 'criticalTemperature_K'; 'criticalPressure_kPa'; 'saturation_temperature_K'; 'saturation_pressure_kPa'; 'enthalpy_vaporization_temperature_K'; 'enthalpy_vaporization_kJ_per_mol'; 'cp_temperature_K'; 'cp_J_per_K_mol'};
            app.dataDisplayTable.ColumnRearrangeable = 'on';
            app.dataDisplayTable.RowName = {};
            app.dataDisplayTable.ColumnEditable = true;
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel = uilabel(app.RequiredTab);
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel.Text = 'CAS number is essential for linking and cross-referencing data between different tables.';
            app.AllinthissectionisusedforLabel = uilabel(app.RequiredTab);
            app.AllinthissectionisusedforLabel.Text = 'All data in this section is used for the optimization of alpha parameters, excluding data will require modification of optimization process. ';
            app.VariableSelectorButtonGroup = uibuttongroup(app.RequiredTab);
            app.VariableSelectorButtonGroup.Title = 'Variable Selector';
            app.criticalTemperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.criticalTemperature_KButton.Text = 'criticalTemperature_K';
            app.criticalTemperature_KButton.Value = true;
            app.criticalPressure_kPaButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.criticalPressure_kPaButton.Text = 'criticalPressure_kPa';
            app.saturation_temperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.saturation_temperature_KButton.Text = 'saturation_temperature_K';
            app.saturation_pressure_kPaButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.saturation_pressure_kPaButton.Text = 'saturation_pressure_kPa';
            app.enthalpy_vaporization_temperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.enthalpy_vaporization_temperature_KButton.Text = 'enthalpy_vaporization_temperature_K';
            app.enthalpy_vaporization_kJ_per_molButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.enthalpy_vaporization_kJ_per_molButton.Text = 'enthalpy_vaporization_kJ_per_mol';
            app.cp_temperature_KButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.cp_temperature_KButton.Text = 'cp_temperature_K';
            app.cp_J_per_K_molButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.cp_J_per_K_molButton.Text = 'cp_J_per_K_mol';
            app.ComponentButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.ComponentButton.Text = 'Component';
            app.CASButton = uitogglebutton(app.VariableSelectorButtonGroup);
            app.CASButton.Text = 'CAS';
            app.ManualDataEntryWindowTextAreaLabel = uilabel(app.RequiredTab);
            app.ManualDataEntryWindowTextAreaLabel.Text = 'Manual Data Entry Window';
            app.ManualDataEntryWindowTextArea = uitextarea(app.RequiredTab);
            app.AddDataButton = uibutton(app.RequiredTab, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add Data';
            app.SaveTableButton = uibutton(app.RequiredTab, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'save2_img.png');
            app.SaveTableButton.Text = 'Save Table';
            app.ClearTableButton = uibutton(app.RequiredTab, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'clear_img.png');
            app.ClearTableButton.Text = 'Clear Table';
            app.ImportDataButton = uibutton(app.RequiredTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'import_image.png');
            app.ImportDataButton.Text = 'Import Data     ';
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel_2 = uilabel(app.RequiredTab);
            app.CASisusedAsauniqueidentifiertolinkdatafromLabel_2.Text = 'unsaved files will be lost!';
            app.ToDataScreeningButton = uibutton(app.RequiredTab, 'push');
            app.ToDataScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @ToDataScreeningButtonPushed, true);
            app.ToDataScreeningButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'screening_image.png');
            app.ToDataScreeningButton.Text = 'To Data Screening';
            app.Image = uiimage(app.RequiredTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GUI_files', 'image', 'arrowR_image.png');
            app.Button = uibutton(app.RequiredTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DataScreenerTab = uitab(app.TabGroup);
            app.DataScreenerTab.Title = 'Data Screener';
            app.UIAxes_ScreeningData = uiaxes(app.DataScreenerTab);
            title(app.UIAxes_ScreeningData, 'Title')
            xlabel(app.UIAxes_ScreeningData, 'X')
            ylabel(app.UIAxes_ScreeningData, 'Y')
            zlabel(app.UIAxes_ScreeningData, 'Z')
            app.PlotAxisPanel = uipanel(app.DataScreenerTab);
            app.PlotAxisPanel.Title = 'Plot Axis';
            app.XAxisLabel = uilabel(app.PlotAxisPanel);
            app.XAxisLabel.Text = 'X-Axis :';
            app.XAxisDropDown = uidropdown(app.PlotAxisPanel);
            app.XAxisDropDown.Items = {'Select X-Axis Variable'};
            app.XAxisDropDown.DropDownOpeningFcn = createCallbackFcn(app, @XAxisDropDownOpening, true);
            app.XAxisDropDown.Value = 'Select X-Axis Variable';
            app.YAxisLabel = uilabel(app.PlotAxisPanel);
            app.YAxisLabel.Text = 'Y-Axis :';
            app.YAxisDropDown = uidropdown(app.PlotAxisPanel);
            app.YAxisDropDown.Items = {'Select Y-Axis Variable'};
            app.YAxisDropDown.DropDownOpeningFcn = createCallbackFcn(app, @YAxisDropDownOpening, true);
            app.YAxisDropDown.Value = 'Select Y-Axis Variable';
            app.PlotDataButton = uibutton(app.PlotAxisPanel, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.ComponentLabel = uilabel(app.PlotAxisPanel);
            app.ComponentLabel.Text = 'Component :';
            app.ComponentDropDown_screener = uidropdown(app.PlotAxisPanel);
            app.ComponentDropDown_screener.Items = {'Select File'};
            app.ComponentDropDown_screener.DropDownOpeningFcn = createCallbackFcn(app, @ComponentDropDown_screenerOpening, true);
            app.ComponentDropDown_screener.Value = 'Select File';
            app.DataTypeDropDownLabel = uilabel(app.PlotAxisPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type :';
            app.DataTypeDropDown_screener = uidropdown(app.PlotAxisPanel);
            app.DataTypeDropDown_screener.DropDownOpeningFcn = createCallbackFcn(app, @DataTypeDropDown_screenerOpening, true);
            app.ToComponentManagerButton = uibutton(app.DataScreenerTab, 'push');
            app.ToComponentManagerButton.ButtonPushedFcn = createCallbackFcn(app, @ToComponentManagerButtonPushed, true);
            app.ToComponentManagerButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'tableBig_img.png');
            app.ToComponentManagerButton.Text = {'To Component '; 'Manager'};
            app.ScreeningProcessTab = uitabgroup(app.DataScreenerTab);
            app.HomeTab = uitab(app.ScreeningProcessTab);
            app.HomeTab.Title = 'Home';
            app.SaveDataButton = uibutton(app.HomeTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'save2_img.png');
            app.SaveDataButton.Text = 'Save Data';
            app.StartScreeningButton = uibutton(app.HomeTab, 'push');
            app.StartScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @StartScreeningButtonPushed, true);
            app.StartScreeningButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'screening_image.png');
            app.StartScreeningButton.Text = 'Start Screening  ';
            app.ScreeningTypeTab = uitab(app.ScreeningProcessTab);
            app.ScreeningTypeTab.Title = 'Screening  Type';
            app.ManualButton = uibutton(app.ScreeningTypeTab, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.AutoViaRegressionTreesButton = uibutton(app.ScreeningTypeTab, 'push');
            app.AutoViaRegressionTreesButton.ButtonPushedFcn = createCallbackFcn(app, @AutoViaRegressionTreesButtonPushed, true);
            app.AutoViaRegressionTreesButton.Text = {'Auto  '; '( Via Regression Trees )'};
            app.ManualTab = uitab(app.ScreeningProcessTab);
            app.ManualTab.Title = 'Manual';
            app.RemoveMarkedOutliersButton = uibutton(app.ManualTab, 'push');
            app.RemoveMarkedOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveMarkedOutliersButtonPushed, true);
            app.RemoveMarkedOutliersButton.Text = {'Remove '; 'Marked Outliers'};
            app.CompleteManualScreeningButton = uibutton(app.ManualTab, 'push');
            app.CompleteManualScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @CompleteManualScreeningButtonPushed, true);
            app.CompleteManualScreeningButton.Text = {'Complete Manual'; 'Screening'};
            app.UsetheplotbrushtooltomarkupdataLabel = uilabel(app.ManualTab);
            app.UsetheplotbrushtooltomarkupdataLabel.Text = 'Use the plot brush tool to mark up data.';
            app.AutoTab = uitab(app.ScreeningProcessTab);
            app.AutoTab.Title = 'Auto  ';
            app.StartAutoScreeningButton = uibutton(app.AutoTab, 'push');
            app.StartAutoScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @StartAutoScreeningButtonPushed, true);
            app.StartAutoScreeningButton.Text = 'Start Auto Screening';
            app.AcceptOutliersButton = uibutton(app.AutoTab, 'push');
            app.AcceptOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptOutliersButtonPushed, true);
            app.AcceptOutliersButton.Text = 'Accept Outliers';
            app.RejectOutliersButton = uibutton(app.AutoTab, 'push');
            app.RejectOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RejectOutliersButtonPushed, true);
            app.RejectOutliersButton.Text = 'Reject Outliers';
            app.CompleteScreeningButton = uibutton(app.AutoTab, 'push');
            app.CompleteScreeningButton.ButtonPushedFcn = createCallbackFcn(app, @CompleteScreeningButtonPushed, true);
            app.CompleteScreeningButton.Text = 'Complete Screening';
            app.InputParametersPanel = uipanel(app.AutoTab);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.BinningSizeLabel = uilabel(app.InputParametersPanel);
            app.BinningSizeLabel.Text = 'Binning Size :';
            app.BinningSizeEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.BinningSizeEditField.ValueDisplayFormat = '%.0f';
            app.BinningSizeEditField.Value = 50;
            app.MultiplierfortheIQRFenceLabel = uilabel(app.InputParametersPanel);
            app.MultiplierfortheIQRFenceLabel.Text = 'Multiplier for the IQR Fence :';
            app.MultiplierfortheIQRFenceEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.MultiplierfortheIQRFenceEditField.ValueDisplayFormat = '%.1f';
            app.MultiplierfortheIQRFenceEditField.Value = 1.5;
            app.NumberofTreesEditFieldLabel = uilabel(app.InputParametersPanel);
            app.NumberofTreesEditFieldLabel.Text = 'Number of Trees :';
            app.NumberofTreesEditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.NumberofTreesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofTreesEditField.Value = 100;
            app.table_screeningData = uitable(app.DataScreenerTab);
            app.table_screeningData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.table_screeningData.RowName = {};
            app.table_sourceData = uitable(app.DataScreenerTab);
            app.table_sourceData.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.table_sourceData.RowName = {};
            app.SourceDataLabel = uilabel(app.DataScreenerTab);
            app.SourceDataLabel.Text = 'Source Data';
            app.ScreenedDataLabel = uilabel(app.DataScreenerTab);
            app.ScreenedDataLabel.Text = 'Screened Data';
            app.ToOptimizerButton = uibutton(app.DataScreenerTab, 'push');
            app.ToOptimizerButton.ButtonPushedFcn = createCallbackFcn(app, @ToOptimizerButtonPushed, true);
            app.ToOptimizerButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'optimization_img.png');
            app.ToOptimizerButton.Text = 'To Optimizer';
            app.OptimizerTab = uitab(app.TabGroup);
            app.OptimizerTab.Title = 'Optimizer';
            app.Panel = uipanel(app.OptimizerTab);
            app.Panel.Title = 'Panel';
            app.WeighingFactorsSetLabel = uilabel(app.Panel);
            app.WeighingFactorsSetLabel.Text = 'Weighing Factors Set';
            app.UITable_weightingFactorSet = uitable(app.Panel);
            app.UITable_weightingFactorSet.ColumnName = {'W_P'; 'W_Cp'; 'W_H'};
            app.UITable_weightingFactorSet.RowName = {};
            app.WeighingFactorVariablesButtonGroup = uibuttongroup(app.Panel);
            app.WeighingFactorVariablesButtonGroup.Title = 'Weighing Factor Variables';
            app.W_PButton = uitogglebutton(app.WeighingFactorVariablesButtonGroup);
            app.W_PButton.Text = 'W_P';
            app.W_PButton.Value = true;
            app.W_CpButton = uitogglebutton(app.WeighingFactorVariablesButtonGroup);
            app.W_CpButton.Text = 'W_Cp';
            app.W_HButton = uitogglebutton(app.WeighingFactorVariablesButtonGroup);
            app.W_HButton.Text = 'W_H';
            app.SetNameEditFieldLabel = uilabel(app.WeighingFactorVariablesButtonGroup);
            app.SetNameEditFieldLabel.Text = 'Set Name';
            app.SetNameEditField = uieditfield(app.WeighingFactorVariablesButtonGroup, 'text');
            app.SetNameEditField.Value = 'WeighingFactorsSet';
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'GUI_files', 'image', 'arrowR_image.png');
            app.AddWeighingFactorsButton = uibutton(app.Panel, 'push');
            app.AddWeighingFactorsButton.ButtonPushedFcn = createCallbackFcn(app, @AddWeighingFactorsButtonPushed, true);
            app.AddWeighingFactorsButton.Text = {'Add '; 'Weighing Factors'};
            app.ImportWeightsButton = uibutton(app.Panel, 'push');
            app.ImportWeightsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportWeightsButtonPushed, true);
            app.ImportWeightsButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'import_image.png');
            app.ImportWeightsButton.Text = 'Import Weights';
            app.ManualEntryWindowTextAreaLabel = uilabel(app.Panel);
            app.ManualEntryWindowTextAreaLabel.Text = {'Manual'; ' Entry Window'};
            app.ManualEntryWindow_weightingFactor = uitextarea(app.Panel);
            app.SaveWeightingFactorSetsButton = uibutton(app.Panel, 'push');
            app.SaveWeightingFactorSetsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWeightingFactorSetsButtonPushed, true);
            app.SaveWeightingFactorSetsButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'save_img.png');
            app.SaveWeightingFactorSetsButton.Text = {'Save Weighting'; 'Factor Sets'};
            app.ClearWeighingFactorsSetButton = uibutton(app.Panel, 'push');
            app.ClearWeighingFactorsSetButton.ButtonPushedFcn = createCallbackFcn(app, @ClearWeighingFactorsSetButtonPushed, true);
            app.ClearWeighingFactorsSetButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'clear_img.png');
            app.ClearWeighingFactorsSetButton.Text = {'Clear Weighing '; 'Factors Set'};
            app.Panel2 = uipanel(app.OptimizerTab);
            app.Panel2.Title = 'Panel2';
            app.ModelOptionsPanel = uipanel(app.Panel2);
            app.ModelOptionsPanel.Title = 'Model Options';
            app.CEoSLabel = uilabel(app.ModelOptionsPanel);
            app.CEoSLabel.Text = 'CEoS :';
            app.CEoSDropDown = uidropdown(app.ModelOptionsPanel);
            app.CEoSDropDown.Items = {'Peng-Robinson', 'Redlich-Kwong'};
            app.CEoSDropDown.Value = 'Peng-Robinson';
            app.AlphaFunctionLabel = uilabel(app.ModelOptionsPanel);
            app.AlphaFunctionLabel.Text = 'Alpha Function :';
            app.AlphaFunctionDropDown = uidropdown(app.ModelOptionsPanel);
            app.AlphaFunctionDropDown.Items = {'Twu', 'Soave'};
            app.AlphaFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @AlphaFunctionDropDownValueChanged, true);
            app.AlphaFunctionDropDown.Value = 'Twu';
            app.TabGroup_alphaFunction = uitabgroup(app.Panel2);
            app.TwuTab = uitab(app.TabGroup_alphaFunction);
            app.TwuTab.Title = 'Twu';
            app.InitialEstimationforTwuAlphaParametersPanel = uipanel(app.TwuTab);
            app.InitialEstimationforTwuAlphaParametersPanel.Title = 'Initial Estimation for Twu Alpha Parameters';
            app.Alpha2EditFieldLabel = uilabel(app.InitialEstimationforTwuAlphaParametersPanel);
            app.Alpha2EditFieldLabel.Text = 'Alpha  2';
            app.Alpha2EditField = uieditfield(app.InitialEstimationforTwuAlphaParametersPanel, 'numeric');
            app.Alpha2EditField.Value = 0.5;
            app.Alpha3EditFieldLabel = uilabel(app.InitialEstimationforTwuAlphaParametersPanel);
            app.Alpha3EditFieldLabel.Text = 'Alpha  3';
            app.Alpha3EditField = uieditfield(app.InitialEstimationforTwuAlphaParametersPanel, 'numeric');
            app.Alpha3EditField.Value = 0.5;
            app.Alpha1EditFieldLabel = uilabel(app.InitialEstimationforTwuAlphaParametersPanel);
            app.Alpha1EditFieldLabel.Text = 'Alpha  1';
            app.Alpha1EditField = uieditfield(app.InitialEstimationforTwuAlphaParametersPanel, 'numeric');
            app.Alpha1EditField.Value = -0.5;
            app.TTr_1exp_21Tr_3Label = uilabel(app.InitialEstimationforTwuAlphaParametersPanel);
            app.TTr_1exp_21Tr_3Label.Text = 'α(T)=Tr^(α_1 )  exp⁡(α_2 (1-Tr^(α_3 ) ))';
            app.Label_3 = uilabel(app.InitialEstimationforTwuAlphaParametersPanel);
            app.Label_3.Text = '* where a_1 = Alpha  1, a_2 = Alpha  2, a_1 = Alpha  1';
            app.SoaveTab = uitab(app.TabGroup_alphaFunction);
            app.SoaveTab.Title = 'Soave';
            app.InitialEstimationforSoavemParameterPanel = uipanel(app.SoaveTab);
            app.InitialEstimationforSoavemParameterPanel.Title = 'Initial Estimation for Soave m Parameter';
            app.mLabel = uilabel(app.InitialEstimationforSoavemParameterPanel);
            app.mLabel.Text = 'm =';
            app.mEditField = uieditfield(app.InitialEstimationforSoavemParameterPanel, 'numeric');
            app.mEditField.Value = 1;
            app.Label_4 = uilabel(app.InitialEstimationforSoavemParameterPanel);
            app.Label_4.Text = 'α(T) = (1+m (1-(Tr )^0.5 ))^0.5';
            app.OptimizerSettingsPanel = uipanel(app.Panel2);
            app.OptimizerSettingsPanel.Title = 'Optimizer Settings';
            app.ErrorTypeLabel = uilabel(app.OptimizerSettingsPanel);
            app.ErrorTypeLabel.Text = 'Error Function :';
            app.ErrorFunctionDropDown = uidropdown(app.OptimizerSettingsPanel);
            app.ErrorFunctionDropDown.Items = {'MSLE', 'Relative Error  ', 'Quadratic Error  ', 'Mean Error  '};
            app.ErrorFunctionDropDown.Value = 'MSLE';
            app.ApplyLeGuennecConstraintsCheckBox = uicheckbox(app.OptimizerSettingsPanel);
            app.ApplyLeGuennecConstraintsCheckBox.Text = 'Apply Le Guennec Constraints';
            app.ApplyLeGuennecConstraintsCheckBox.Value = true;
            app.PropertiestooptimizePanel = uipanel(app.OptimizerSettingsPanel);
            app.PropertiestooptimizePanel.Title = 'Properties to optimize';
            app.SaturationPressureCheckBox = uicheckbox(app.PropertiestooptimizePanel);
            app.SaturationPressureCheckBox.Text = 'Saturation Pressure';
            app.SaturationPressureCheckBox.Value = true;
            app.VaporizationEnthalpyCheckBox = uicheckbox(app.PropertiestooptimizePanel);
            app.VaporizationEnthalpyCheckBox.Text = 'Vaporization Enthalpy';
            app.VaporizationEnthalpyCheckBox.Value = true;
            app.SaturatedLiquidHeatCapacityCheckBox = uicheckbox(app.PropertiestooptimizePanel);
            app.SaturatedLiquidHeatCapacityCheckBox.Text = 'Saturated Liquid Heat Capacity';
            app.SaturatedLiquidHeatCapacityCheckBox.Value = true;
            app.CpTrUpperLimitLabel = uilabel(app.OptimizerSettingsPanel);
            app.CpTrUpperLimitLabel.Text = 'Cp Tr Upper Limit :';
            app.CpTrUpperLimitEditField = uieditfield(app.OptimizerSettingsPanel, 'numeric');
            app.CpTrUpperLimitEditField.ValueDisplayFormat = '%.2f';
            app.CpTrUpperLimitEditField.Value = 0.8;
            app.ExperimentalDataSelectionPanel = uipanel(app.OptimizerTab);
            app.ExperimentalDataSelectionPanel.Title = 'Experimental Data Selection';
            app.DataTypeLabel = uilabel(app.ExperimentalDataSelectionPanel);
            app.DataTypeLabel.Text = 'Data Type :';
            app.DataTypeDropDown_optimizer = uidropdown(app.ExperimentalDataSelectionPanel);
            app.DataTypeDropDown_optimizer.DropDownOpeningFcn = createCallbackFcn(app, @DataTypeDropDown_optimizerOpening, true);
            app.ComponentDropDown_2Label = uilabel(app.ExperimentalDataSelectionPanel);
            app.ComponentDropDown_2Label.Text = 'Component :';
            app.ComponentDropDown_optimizer = uidropdown(app.ExperimentalDataSelectionPanel);
            app.ComponentDropDown_optimizer.DropDownOpeningFcn = createCallbackFcn(app, @ComponentDropDown_optimizerOpening, true);
            app.ToDataScreeningButton_2 = uibutton(app.OptimizerTab, 'push');
            app.ToDataScreeningButton_2.ButtonPushedFcn = createCallbackFcn(app, @ToDataScreeningButton_2Pushed, true);
            app.ToDataScreeningButton_2.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'screening_image.png');
            app.ToDataScreeningButton_2.Text = 'To Data Screening';
            app.StartOptimizationButton = uibutton(app.OptimizerTab, 'push');
            app.StartOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @StartOptimizationButtonPushed, true);
            app.StartOptimizationButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'play3_image.png');
            app.StartOptimizationButton.Text = {'Start '; 'Optimization'};
            app.TabGroup_OptimizationOutputs = uitabgroup(app.OptimizerTab);
            app.TwuOutputTab = uitab(app.TabGroup_OptimizationOutputs);
            app.TwuOutputTab.Title = 'Twu';
            app.UITable_TwuOutput = uitable(app.TwuOutputTab);
            app.UITable_TwuOutput.ColumnName = {'run'; 'W_P'; 'W_Cp'; 'W_H'; 'alpha_1'; 'alpha _2'; 'alpha  3'; 'error_P'; 'error_Cp'; 'error_H'; 'fval'; 'run_time'};
            app.UITable_TwuOutput.RowName = {};
            app.OptimizingImage_bar_3 = uiimage(app.TwuOutputTab);
            app.OptimizingImage_bar_3.Visible = 'off';
            app.OptimizingImage_bar_3.ImageSource = fullfile(pathToMLAPP, 'GUI_files', 'image', 'Pulse@1x-1.0s-200px-200px.svg');
            app.OptimizationSetNameEditFieldLabel = uilabel(app.TwuOutputTab);
            app.OptimizationSetNameEditFieldLabel.Text = 'Optimization Set Name';
            app.Twu_OptimizationSetNameEditField = uieditfield(app.TwuOutputTab, 'text');
            app.Twu_SaveOptimizationResultsButton = uibutton(app.TwuOutputTab, 'push');
            app.Twu_SaveOptimizationResultsButton.ButtonPushedFcn = createCallbackFcn(app, @Twu_SaveOptimizationResultsButtonPushed, true);
            app.Twu_SaveOptimizationResultsButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'save2_img.png');
            app.Twu_SaveOptimizationResultsButton.Text = {'Save '; 'Optimization'; 'Results'};
            app.Twu_RunIDLabel = uilabel(app.TwuOutputTab);
            app.Twu_RunIDLabel.Text = 'Run ID:';
            app.Twu_RunIdentifierLabel = uilabel(app.TwuOutputTab);
            app.Twu_RunIdentifierLabel.Text = '--------';
            app.Twu_filePath = uilabel(app.TwuOutputTab);
            app.Twu_filePath.Visible = 'off';
            app.Twu_filePath.Text = 'filePath';
            app.SoaveOutputTab = uitab(app.TabGroup_OptimizationOutputs);
            app.SoaveOutputTab.Title = 'Soave';
            app.UITable_SoaveOutput = uitable(app.SoaveOutputTab);
            app.UITable_SoaveOutput.ColumnName = {'run'; 'W_P'; 'W_Cp'; 'W_H'; 'm_parameter'; 'error_P'; 'error_Cp'; 'error_H'; 'fval'; 'run_time'};
            app.UITable_SoaveOutput.RowName = {};
            app.OptimizingImage_bar_2 = uiimage(app.SoaveOutputTab);
            app.OptimizingImage_bar_2.Visible = 'off';
            app.OptimizingImage_bar_2.ImageSource = fullfile(pathToMLAPP, 'GUI_files', 'image', 'Pulse@1x-1.0s-200px-200px.svg');
            app.Soave_SaveOptimizationResultsButton = uibutton(app.SoaveOutputTab, 'push');
            app.Soave_SaveOptimizationResultsButton.ButtonPushedFcn = createCallbackFcn(app, @Soave_SaveOptimizationResultsButtonPushed, true);
            app.Soave_SaveOptimizationResultsButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'save2_img.png');
            app.Soave_SaveOptimizationResultsButton.Text = {'Save '; 'Optimization'; 'Results'};
            app.OptimizationSetNameEditFieldLabel_2 = uilabel(app.SoaveOutputTab);
            app.OptimizationSetNameEditFieldLabel_2.Text = 'Optimization Set Name';
            app.Soave_OptimizationSetNameEditField = uieditfield(app.SoaveOutputTab, 'text');
            app.Soave_RunIDLabel = uilabel(app.SoaveOutputTab);
            app.Soave_RunIDLabel.Text = 'Run ID:';
            app.Soave_RunIdentifierLabel = uilabel(app.SoaveOutputTab);
            app.Soave_RunIdentifierLabel.Text = '--------';
            app.Soave_filePath = uilabel(app.SoaveOutputTab);
            app.Soave_filePath.Visible = 'off';
            app.Soave_filePath.Text = 'filePath';
            app.LogTab = uitab(app.TabGroup_OptimizationOutputs);
            app.LogTab.Title = 'Log';
            app.OptimizationLogTextAreaLabel = uilabel(app.LogTab);
            app.OptimizationLogTextAreaLabel.Text = 'Optimization Log';
            app.OptimizationProgressTextArea = uitextarea(app.LogTab);
            app.OptimizingImage_bar_1 = uiimage(app.LogTab);
            app.OptimizingImage_bar_1.Visible = 'off';
            app.OptimizingImage_bar_1.ImageSource = fullfile(pathToMLAPP, 'GUI_files', 'image', 'Pulse@1x-1.0s-200px-200px.svg');
            app.Label_infoTextOptimzationTab = uilabel(app.OptimizerTab);
            app.Label_infoTextOptimzationTab.Text = {'* Where W =  weighting factor, Tr = reduced temperature, MSLE = Mean Square Logarithmic Error  '; '   P= Saturation pressure, Cp= Saturated liquid heat capacity , H  = enthalpy of vaporization'};
            app.OptimizingImage_spin = uiimage(app.OptimizerTab);
            app.OptimizingImage_spin.Visible = 'off';
            app.OptimizingImage_spin.ImageSource = fullfile(pathToMLAPP, 'GUI_files', 'image', 'Double Ring@1x-2.4s-200px-200px.svg');
            app.OptimizingLabel = uilabel(app.OptimizerTab);
            app.OptimizingLabel.Visible = 'off';
            app.OptimizingLabel.Text = 'Optimizing...';
            app.Label_infoText2OptimzationTab = uilabel(app.OptimizerTab);
            app.Label_infoText2OptimzationTab.Text = '* fval = Gives the minimum error evaluated by the optimizer';
            app.DisplayOptionButtonGroup = uibuttongroup(app.OptimizerTab);
            app.DisplayOptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayOptionButtonGroupSelectionChanged, true);
            app.DisplayOptionButtonGroup.Title = 'Display Option';
            app.OutputTableButton = uitogglebutton(app.DisplayOptionButtonGroup);
            app.OutputTableButton.Text = 'Output Table';
            app.OutputTableButton.Value = true;
            app.LogButton = uitogglebutton(app.DisplayOptionButtonGroup);
            app.LogButton.Text = 'Log';
            app.ToDataPlottingButton = uibutton(app.OptimizerTab, 'push');
            app.ToDataPlottingButton.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'screening_image.png');
            app.ToDataPlottingButton.Text = 'To Data Plotting';
            app.PlotterTab = uitab(app.TabGroup);
            app.PlotterTab.Title = 'Plotter';
            app.UIAxes = uiaxes(app.PlotterTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ModelSeletionPanel = uipanel(app.PlotterTab);
            app.ModelSeletionPanel.Title = 'Model Seletion ';
            app.CEoSLabel_2 = uilabel(app.ModelSeletionPanel);
            app.CEoSLabel_2.Text = 'CEoS :';
            app.CEoSDropDown_2 = uidropdown(app.ModelSeletionPanel);
            app.CEoSDropDown_2.Items = {'Peng-Robinson', 'Redlich-Kwong'};
            app.CEoSDropDown_2.Value = 'Peng-Robinson';
            app.AlphaFunctionDropDown_2Label = uilabel(app.ModelSeletionPanel);
            app.AlphaFunctionDropDown_2Label.Text = 'Alpha Function :';
            app.AlphaFunctionDropDown_2 = uidropdown(app.ModelSeletionPanel);
            app.AlphaFunctionDropDown_2.Items = {'Twu', 'Soave', 'Both'};
            app.AlphaFunctionDropDown_2.Value = 'Twu';
            app.PropertyDropDownLabel = uilabel(app.ModelSeletionPanel);
            app.PropertyDropDownLabel.Text = 'Property :';
            app.PropertyDropDown = uidropdown(app.ModelSeletionPanel);
            app.PropertyDropDown.Items = {'Saturation Pressure, kPa', 'Vaporization Enthalpy, kJ mol^-1', 'Heat Capacity at Constant Pressure, J mol^-1 K^-1', 'Heat Capacity at Constant Volume, J mol^-1 K^-1', 'Liquid Density, kg m^-3', 'Gas Density, kg/m^-3', 'Speed of Sound, m s^-1', 'Reduced Waring Number ', 'dP_dV, kPa mol', 'dP_dT, kPa K^-1', 'b, beta, kPa^-1'};
            app.PropertyDropDown.Value = 'Saturation Pressure, kPa';
            app.TabGroup3 = uitabgroup(app.PlotterTab);
            app.TwuTab_2 = uitab(app.TabGroup3);
            app.TwuTab_2.Title = 'Twu';
            app.AlphaParmatersSourceButtonGroup_Twu = uibuttongroup(app.TwuTab_2);
            app.AlphaParmatersSourceButtonGroup_Twu.SelectionChangedFcn = createCallbackFcn(app, @AlphaParmatersSourceButtonGroup_TwuSelectionChanged, true);
            app.AlphaParmatersSourceButtonGroup_Twu.Title = 'Alpha Parmaters Source';
            app.FromDataSetButton_Plotter_Twu = uitogglebutton(app.AlphaParmatersSourceButtonGroup_Twu);
            app.FromDataSetButton_Plotter_Twu.Text = {'From '; 'Data Set'};
            app.FromDataSetButton_Plotter_Twu.Value = true;
            app.ManualEntryButton_Plotter_Twu = uitogglebutton(app.AlphaParmatersSourceButtonGroup_Twu);
            app.ManualEntryButton_Plotter_Twu.Text = {'Manual '; 'Entry'};
            app.TabGroup_Plotter_Twu = uitabgroup(app.TwuTab_2);
            app.DataSetTab_Plotter_Twu = uitab(app.TabGroup_Plotter_Twu);
            app.DataSetTab_Plotter_Twu.Title = 'Data Set';
            app.ComponentLabel_3 = uilabel(app.DataSetTab_Plotter_Twu);
            app.ComponentLabel_3.Text = 'Component :';
            app.ComponentDropDown_plotter_Twu = uidropdown(app.DataSetTab_Plotter_Twu);
            app.ComponentDropDown_plotter_Twu.DropDownOpeningFcn = createCallbackFcn(app, @ComponentDropDown_plotter_Twu_Opening, true);
            app.DataTypeDropDown_2Label_2 = uilabel(app.DataSetTab_Plotter_Twu);
            app.DataTypeDropDown_2Label_2.Text = 'Data Type';
            app.DataTypeDropDown_plotter_Twu = uidropdown(app.DataSetTab_Plotter_Twu);
            app.DataTypeDropDown_plotter_Twu.DropDownOpeningFcn = createCallbackFcn(app, @DataTypeDropDown_plotter_Twu_Opening, true);
            app.RunIDDropDownLabel = uilabel(app.DataSetTab_Plotter_Twu);
            app.RunIDDropDownLabel.Text = 'Run ID:';
            app.RunIDDropDown_plotter_Twu = uidropdown(app.DataSetTab_Plotter_Twu);
            app.RunIDDropDown_plotter_Twu.DropDownOpeningFcn = createCallbackFcn(app, @RunIDDropDown_plotter_TwuOpening, true);
            app.RunIDDropDown_plotter_Twu.ValueChangedFcn = createCallbackFcn(app, @RunIDDropDown_plotter_TwuValueChanged, true);
            app.ManualTab_Plotter_Twu = uitab(app.TabGroup_Plotter_Twu);
            app.ManualTab_Plotter_Twu.Title = 'Manual';
            app.Alpha1EditField_3Label = uilabel(app.ManualTab_Plotter_Twu);
            app.Alpha1EditField_3Label.Text = 'Alpha 1';
            app.Alpha1EditField_plotter = uieditfield(app.ManualTab_Plotter_Twu, 'numeric');
            app.Alpha2EditField_3Label = uilabel(app.ManualTab_Plotter_Twu);
            app.Alpha2EditField_3Label.Text = 'Alpha 2';
            app.Alpha2EditField_plotter = uieditfield(app.ManualTab_Plotter_Twu, 'numeric');
            app.Alpha3EditField_3Label = uilabel(app.ManualTab_Plotter_Twu);
            app.Alpha3EditField_3Label.Text = 'Alpha 3';
            app.Alpha3EditField_plotter = uieditfield(app.ManualTab_Plotter_Twu, 'numeric');
            app.UpdateParametersButton_Twu = uibutton(app.ManualTab_Plotter_Twu, 'push');
            app.UpdateParametersButton_Twu.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButton_TwuPushed, true);
            app.UpdateParametersButton_Twu.Text = {'Update'; 'Parameters'};
            app.UITable_alphaTable_Twu = uitable(app.TwuTab_2);
            app.UITable_alphaTable_Twu.ColumnName = {'Alpha 1'; 'Alpha 2'; 'Alpha 3'};
            app.UITable_alphaTable_Twu.RowName = {};
            app.SoaveTab_Soave = uitab(app.TabGroup3);
            app.SoaveTab_Soave.Title = 'Soave';
            app.AlphaParametersSourceButtonGroup_Soave = uibuttongroup(app.SoaveTab_Soave);
            app.AlphaParametersSourceButtonGroup_Soave.SelectionChangedFcn = createCallbackFcn(app, @AlphaParametersSourceButtonGroup_SoaveSelectionChanged, true);
            app.AlphaParametersSourceButtonGroup_Soave.Title = 'Alpha Parameters Source';
            app.FromDataSetButton_Plotter_Soave = uitogglebutton(app.AlphaParametersSourceButtonGroup_Soave);
            app.FromDataSetButton_Plotter_Soave.Text = {'From '; 'Data Set'};
            app.FromDataSetButton_Plotter_Soave.Value = true;
            app.ManualEntryButton_Plotter_Soave = uitogglebutton(app.AlphaParametersSourceButtonGroup_Soave);
            app.ManualEntryButton_Plotter_Soave.Text = {'Manual '; 'Entry'};
            app.GeneralizedModelButton_Plotter_Soave = uitogglebutton(app.AlphaParametersSourceButtonGroup_Soave);
            app.GeneralizedModelButton_Plotter_Soave.Text = {'Generalized '; 'Model'};
            app.TabGroup_Plotter_Soave = uitabgroup(app.SoaveTab_Soave);
            app.DataSetTab_Plotter_Soave = uitab(app.TabGroup_Plotter_Soave);
            app.DataSetTab_Plotter_Soave.Title = 'Data Set';
            app.ComponentLabel_4 = uilabel(app.DataSetTab_Plotter_Soave);
            app.ComponentLabel_4.Text = 'Component :';
            app.ComponentDropDown_plotter_Soave = uidropdown(app.DataSetTab_Plotter_Soave);
            app.ComponentDropDown_plotter_Soave.DropDownOpeningFcn = createCallbackFcn(app, @ComponentDropDown_plotter_SoaveOpening, true);
            app.DataTypeDropDown_2Label_3 = uilabel(app.DataSetTab_Plotter_Soave);
            app.DataTypeDropDown_2Label_3.Text = 'Data Type';
            app.DataTypeDropDown_plotter_Soave = uidropdown(app.DataSetTab_Plotter_Soave);
            app.DataTypeDropDown_plotter_Soave.DropDownOpeningFcn = createCallbackFcn(app, @DataTypeDropDown_plotter_SoaveOpening, true);
            app.RunDropDown_3Label = uilabel(app.DataSetTab_Plotter_Soave);
            app.RunDropDown_3Label.Text = 'Run :';
            app.RunIDDropDown_plotter_Soave = uidropdown(app.DataSetTab_Plotter_Soave);
            app.RunIDDropDown_plotter_Soave.DropDownOpeningFcn = createCallbackFcn(app, @RunIDDropDown_plotter_SoaveOpening, true);
            app.RunIDDropDown_plotter_Soave.ValueChangedFcn = createCallbackFcn(app, @RunIDDropDown_plotter_SoaveValueChanged, true);
            app.ManualTab_Plotter_Soave = uitab(app.TabGroup_Plotter_Soave);
            app.ManualTab_Plotter_Soave.Title = 'Manual';
            app.mLabel_2 = uilabel(app.ManualTab_Plotter_Soave);
            app.mLabel_2.Text = 'm  Parameter:';
            app.mParameterEditField = uieditfield(app.ManualTab_Plotter_Soave, 'numeric');
            app.UpdateParametersButton_Soave = uibutton(app.ManualTab_Plotter_Soave, 'push');
            app.UpdateParametersButton_Soave.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButton_SoavePushed, true);
            app.UpdateParametersButton_Soave.Text = {'Update'; 'Parameters'};
            app.GeneralizedModelTab = uitab(app.TabGroup_Plotter_Soave);
            app.GeneralizedModelTab.Title = 'Generalized';
            app.GeneralizedModelLabel = uilabel(app.GeneralizedModelTab);
            app.GeneralizedModelLabel.Text = 'Generalized Model :';
            app.GeneralizedModelDropDown = uidropdown(app.GeneralizedModelTab);
            app.GeneralizedModelDropDown.Items = {'Soave', 'Pina et al.'};
            app.GeneralizedModelDropDown.Value = 'Soave';
            app.UITable_mParameterTable_Soave = uitable(app.SoaveTab_Soave);
            app.UITable_mParameterTable_Soave.ColumnName = {'mParameter:'};
            app.UITable_mParameterTable_Soave.RowName = {};
            app.ToOptimizerButton_2 = uibutton(app.PlotterTab, 'push');
            app.ToOptimizerButton_2.Icon = fullfile(pathToMLAPP, 'GUI_files', 'image', 'optimization_img.png');
            app.ToOptimizerButton_2.Text = 'To Optimizer';
            app.UITable = uitable(app.PlotterTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTP_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end